classdef TCPAT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TCPAT2UIFigure                 matlab.ui.Figure
        ReferencesList                 matlab.ui.control.HTML
        WorkingLabel                   matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        LoadTab                        matlab.ui.container.Tab
        LDDistanceEditField            matlab.ui.control.NumericEditField
        DistancebetweenupstreamanddownstreammLabel  matlab.ui.control.Label
        LDTimestepDropDown             matlab.ui.control.DropDown
        LDTimestepEditField            matlab.ui.control.NumericEditField
        TimestepLabel                  matlab.ui.control.Label
        LDDownstreamDatafileBrowseButton  matlab.ui.control.Button
        LDDatafileBrowseButton         matlab.ui.control.Button
        LDDownstreamDatafileEditField  matlab.ui.control.EditField
        LDSeperatedownstreamCheckBox   matlab.ui.control.CheckBox
        LDDatafileEditField            matlab.ui.control.EditField
        DatafileLabel                  matlab.ui.control.Label
        PreProcessingTab               matlab.ui.container.Tab
        PPUnitsDropDown                matlab.ui.control.DropDown
        PPDownstreamCalibration        matlab.ui.control.NumericEditField
        DownstreamLabel                matlab.ui.control.Label
        PPUpstreamCalibration          matlab.ui.control.NumericEditField
        UpstreamEditFieldLabel         matlab.ui.control.Label
        PPTimeZero                     matlab.ui.control.CheckBox
        PPMedianfilterCheckBox         matlab.ui.control.CheckBox
        PPMovingaverageEditField       matlab.ui.control.NumericEditField
        PPMovingaverageCheckBox        matlab.ui.control.CheckBox
        PPCurrentMassbalanceEditField  matlab.ui.control.NumericEditField
        CurrentMassbalanceLabel        matlab.ui.control.Label
        PPtoEditField                  matlab.ui.control.NumericEditField
        toLabel                        matlab.ui.control.Label
        PPFromEditField                matlab.ui.control.NumericEditField
        FromEditFieldLabel             matlab.ui.control.Label
        PPTrimDropDown                 matlab.ui.control.DropDown
        PPSubtractBackgroundButtonGroup  matlab.ui.container.ButtonGroup
        PPSBDownstreamLabel            matlab.ui.control.Label
        PPSBUpstreamLabel              matlab.ui.control.Label
        PPManualDSEditField            matlab.ui.control.NumericEditField
        PPLinearEditField              matlab.ui.control.NumericEditField
        PPManualUSEditField            matlab.ui.control.NumericEditField
        PPConstantDropDown             matlab.ui.control.DropDown
        PPManualButton                 matlab.ui.control.RadioButton
        PPLinearButton                 matlab.ui.control.RadioButton
        PPConstantButton               matlab.ui.control.RadioButton
        PPOffButton                    matlab.ui.control.RadioButton
        PPMassbalanceCheckBox          matlab.ui.control.CheckBox
        PPTrimCheckBox                 matlab.ui.control.CheckBox
        PPCalibrateCheckBox            matlab.ui.control.CheckBox
        MomentsTab                     matlab.ui.container.Tab
        MOMUITable                     matlab.ui.control.Table
        ADETab                         matlab.ui.container.Tab
        ADEDEditField_2                matlab.ui.control.NumericEditField
        Dm2sEditFieldLabel_2           matlab.ui.control.Label
        ADEUEditField_2                matlab.ui.control.NumericEditField
        VelocitymsLabel_2              matlab.ui.control.Label
        ADEtbarEditField_2             matlab.ui.control.NumericEditField
        TravelTimesecondsLabel_2       matlab.ui.control.Label
        ADEOptimiseButton              matlab.ui.control.Button
        ADEOptimisedRoutingLabel       matlab.ui.control.Label
        ADEFromMomentsButton           matlab.ui.control.Button
        ADEManualAdvectionDispersionEquationRoutingLabel  matlab.ui.control.Label
        ADEManualoptionsButtonGroup    matlab.ui.container.ButtonGroup
        ADEFullmanualButton            matlab.ui.control.RadioButton
        ADEAutoUButton                 matlab.ui.control.RadioButton
        ADEAutotbarButton              matlab.ui.control.RadioButton
        ADEDEditField                  matlab.ui.control.NumericEditField
        Dm2sEditFieldLabel             matlab.ui.control.Label
        ADEUEditField                  matlab.ui.control.NumericEditField
        VelocitymsLabel                matlab.ui.control.Label
        ADEtbarEditField               matlab.ui.control.NumericEditField
        TravelTimesecondsLabel         matlab.ui.control.Label
        ADZTab                         matlab.ui.container.Tab
        ADZTravelTimeEditField_2       matlab.ui.control.NumericEditField
        TravelTimesecondsLabel_4       matlab.ui.control.Label
        ADZTravelTimeEditField         matlab.ui.control.NumericEditField
        TravelTimesecondsLabel_3       matlab.ui.control.Label
        ADZOptimise                    matlab.ui.control.Button
        ADZTEditField_2                matlab.ui.control.NumericEditField
        TsecondsLabel_2                matlab.ui.control.Label
        ADZTimeDelayEditField_2        matlab.ui.control.NumericEditField
        TimeDelaysecondsLabel_2        matlab.ui.control.Label
        ADZFromMomentsButton           matlab.ui.control.Button
        ADZTEditField                  matlab.ui.control.NumericEditField
        TsecondsLabel                  matlab.ui.control.Label
        ADZOptimisedADZLabel           matlab.ui.control.Label
        ADZManualADZLabel              matlab.ui.control.Label
        ADZTimeDelayEditField          matlab.ui.control.NumericEditField
        TimeDelaysecondsLabel          matlab.ui.control.Label
        RTDTab                         matlab.ui.container.Tab
        DEDeconvRTDLengthCheckBox      matlab.ui.control.CheckBox
        DEDeconvRTDLengthEditField     matlab.ui.control.NumericEditField
        DEDeconvoleButton              matlab.ui.control.Button
        DELAMASmoothingCheckBox        matlab.ui.control.CheckBox
        DENumberofSamplePointsEditField  matlab.ui.control.NumericEditField
        NumberofSamplePointsLabel      matlab.ui.control.Label
        DEDeconvAxes                   matlab.ui.control.UIAxes
        SummaryTab                     matlab.ui.container.Tab
        SummaryUpdateButton            matlab.ui.control.Button
        SummarySavePictureLabel        matlab.ui.control.Label
        SummaryDesignationNotesEditField  matlab.ui.control.EditField
        DesignationNotesLabel          matlab.ui.control.Label
        SummaryDeconvCheckBox          matlab.ui.control.CheckBox
        SummaryDeconvGuessCheckBox     matlab.ui.control.CheckBox
        SummaryADZOptimisedCheckBox    matlab.ui.control.CheckBox
        SummaryUpdateCalcButton        matlab.ui.control.Button
        SummaryADZManualCheckBox       matlab.ui.control.CheckBox
        SummarySaveButton              matlab.ui.control.Button
        SummaryADEOptimisedCheckBox    matlab.ui.control.CheckBox
        SummaryADEManualCheckBox       matlab.ui.control.CheckBox
        AboutTab                       matlab.ui.container.Tab
        AboutHyperlink_2               matlab.ui.control.Hyperlink
        AboutHyperlink                 matlab.ui.control.Hyperlink
        AboutLabel                     matlab.ui.control.Label
        AboutLogoImage                 matlab.ui.control.Image
        dataaxes                       matlab.ui.control.UIAxes
    end


    % The main TCPAT2 application
    
    % MIT License
    % Copyright (c) 2018-2024 Fred Sonnenwald

    % variables used throughout the application
    properties (Access = private)

        usf % upstream data as read from file (made seconds)
        dsf % downstream data as read from file (made seconds)
        
        time % matching time record in seconds
        usr % raw upstream data
        dsr % raw downstream data
        
        times % time record in seconds, sampled with new time step
        usrs % raw upstream data, sampled with new time step
        dsrs % raw downstream data, sampled with new time step
        
        timep % time record in seconds, sampled with new time step
        usp % pre-processed upstream
        dsp % pre-processed downstream
        uscdata % upstream calibration specification
        dscdata % downstream calibration specification
        
        % for analysis
        moments
        traceptimes % start and end of traces (in timep)
        adeopt % tbar, U, D
        adzopt % T, time delay
        rtdguess % guessed RTD
        rtd % deconvolved RTD
        
        % for plotting lines, repmat size in style should match length of colours
%         styles = [num2cell(repmat('-', 7, 1), 2); num2cell(repmat('--', 7, 1), 2); num2cell(repmat('-.', 7, 1), 2)];
%         colors = [0 0 1; 0 0.5 0; 1 0 0; 0 0.75 0.75; 0.75 0 0.75; 0.75 0.75 0; 0.25 0.25 0.25];
        styles = [num2cell(repmat('-', 9, 1), 2); num2cell(repmat('--', 9, 1), 2); num2cell(repmat('-.', 9, 1), 2)];
        colors = [ 1 0.05 0.05; 0.12 0.12 1; 0 0.75 0.75; 0.75 0 0.75; 1 0.55 0.76; 1 0.65 0; 0.17 0.57 0.11; 0.25 0.25 0.25; 0.65 0.65 0.65];
        leg
        lineat
        
        % way to keep track of if an optimisation is running so that we
        % don't start a second one
        calculating % in the process of calculating
        win % html window (via cef)
        tabIDs % widget ids of tabs
        
        % supported file types
        filetypes = {'*.csv;*.CSV', 'Comma Separated Value (*.csv)'; ...
                '*.xlsx;*.XLSX;*.xls;*.XLS', 'Microsoft Excel (*.xls, *.xlsx)'; ...
                '*.txt;*.TXT;*.dat;*.DAT', 'Text Delimited File (*.txt,*.dat)'; ...
            };
        
        % for saving collect plot data here then write it out
        saving % in the process of saving
        savedata % data to be saved
        mynicedataloader % dialog for handing loading data nicely
    end
    
    methods (Access = private)
        
        % update the plot title when the tab changes
        function updateAxesTitle(app)
            if app.TabGroup.SelectedTab == app.LoadTab
                title(app.dataaxes, 'Raw Data')
            else
                title(app.dataaxes, ' ')
            end
        end
    
        % add a processed data line to the main plot
        function plotLine(app, toplot, description)
            if length(toplot) == length(app.timep)
                % plot the line and add a corresponding legend entry with
                % its goodness-of-fit
                line(app.dataaxes, unitConversion(app, app.timep), toplot, ...
                    'Color', app.colors(mod(app.lineat-1, length(app.colors))+1,:), ...
                    'LineStyle', app.styles{app.lineat} ...
                    );
                app.leg{app.lineat} = sprintf('%s R_t^2 = %0.3f', description, rtSquared(app.dsp, toplot));
                app.lineat = app.lineat + 1;
                
                % append to data for saving if in the process of preparing
                % for saving/exporting data
                if app.saving
                    app.savedata = [app.savedata toplot];
                end
            else
                % we should never reach this
                if length(toplot)~= 1 && toplot ~= 0
                    error('Something has gone wrong with plotting')
                end
            end
        end
    
        % main function called when the tab or an option is changed to update the
        % processing and plot
        function updateAxesPlot(app, docalc)
            % lock out the UI while we work
            app.WorkingLabel.Text = 'Working...';
            drawnow nocallbacks
            fprintf('Updating axes...\n');
            lockTabs(app);
            if docalc == 1
                fprintf('Setting calculating lock...\n');
                app.calculating = 1;
            end
            cla(app.dataaxes)
%             drawnow nocallbacks
            
            set(app.dataaxes, 'ColorOrder', app.colors)
            set(app.dataaxes, 'DefaultLineLineWidth', 1.2)
            
            % start reconstructing the plot legend and data
            app.leg = {'Upstream', 'Downstream'};
            app.lineat = 3;
            
            if isempty(app.timep) || length(app.timep) <= 1
                fprintf('Skipping calculations due to no data...\n')
                docalc = 0;
            end
            
            % what we are plotting depends on which tab is selected
            if app.TabGroup.SelectedTab == app.LoadTab
                % plot the raw data as the individual data points
                plot(app.dataaxes, unitConversion(app, app.times), [app.usrs app.dsrs], 'o', 'MarkerSize', 4)
            else
                % refresh the pre-processed data if on the pre-processing
                % tab (because we've probably changed an option) or if the
                % data hasn't been pre-processed yet
                if app.TabGroup.SelectedTab == app.PreProcessingTab || (~isempty(app.times) && isempty(app.timep))
                    dataPreprocessing(app)
                end
                % plot the data
                plot(app.dataaxes, unitConversion(app, app.timep), [app.usp app.dsp], '-')
                if app.saving == 1
                    app.savedata = [unitConversion(app, app.timep) app.usp app.dsp];
                end
                
                % do ADE processing and add results to the plot
                if app.TabGroup.SelectedTab == app.ADETab || ...
                        (app.TabGroup.SelectedTab == app.SummaryTab && ...
                            (app.SummaryADEManualCheckBox.Value == 1 || app.SummaryADEOptimisedCheckBox.Value == 1))
                    % this happens either on the ADE tab or when adding ADE
                    % to the summary plot
                    [ds, dsopt] = updateADErouting(app, docalc);
                    if app.TabGroup.SelectedTab == app.SummaryTab % alert user if analysis hasn't been run yet
                        if app.SummaryADEManualCheckBox.Value == 1 && isscalar(ds)
                            uialert(app.TCPAT2UIFigure, 'ADE Manual selected, but not calculated', 'Notice', 'Icon', 'info')
                        end
                        if app.SummaryADEOptimisedCheckBox.Value == 1 && isscalar(dsopt)
                            uialert(app.TCPAT2UIFigure, 'ADE Optimised selected, but not calculated', 'Notice', 'Icon', 'info')
                        end
                    end
                    if app.TabGroup.SelectedTab == app.ADETab || app.SummaryADEManualCheckBox.Value == 1
                        plotLine(app, ds, 'Manual ADE')
                    end
                    if app.TabGroup.SelectedTab == app.ADETab || app.SummaryADEOptimisedCheckBox.Value == 1
                        plotLine(app, dsopt, 'Optimised ADE')
                    end
                end
                
                % do ADZ processing and add results to the plot
                if app.TabGroup.SelectedTab == app.ADZTab || ...
                        (app.TabGroup.SelectedTab == app.SummaryTab && ...
                            (app.SummaryADZManualCheckBox.Value == 1 || app.SummaryADZOptimisedCheckBox.Value == 1))
                    % this happens either on the ADZ tab or when adding ADE
                    % to the summary plot
                    [ds, dsopt] = updateADZ(app, docalc);
                    if app.TabGroup.SelectedTab == app.SummaryTab
                        if app.SummaryADZManualCheckBox.Value == 1 && isscalar(ds)
                            uialert(app.TCPAT2UIFigure, 'ADZ Manual selected, but not calculated', 'Notice', 'Icon', 'info')
                        end
                        if app.SummaryADZOptimisedCheckBox.Value == 1 && isscalar(dsopt)
                            uialert(app.TCPAT2UIFigure, 'ADZ Optimised selected, but not calculated', 'Notice', 'Icon', 'info')
                        end
                    end
                    if app.TabGroup.SelectedTab == app.ADZTab || app.SummaryADZManualCheckBox.Value == 1
                        plotLine(app, ds, 'Manual ADZ')
                    end
                    if app.TabGroup.SelectedTab == app.ADZTab || app.SummaryADZOptimisedCheckBox.Value == 1
                        plotLine(app, dsopt, 'Optimised ADZ')
                    end
                end
                
                % do RTD processing and add results to the plot
                if app.TabGroup.SelectedTab == app.RTDTab || ...
                        (app.TabGroup.SelectedTab == app.SummaryTab && ...
                            (app.SummaryDeconvGuessCheckBox.Value == 1 || app.SummaryDeconvCheckBox.Value == 1))
                    % this happens either on the RTD tab or when adding ADE
                    % to the summary plot
                    [ds, dsopt] = updateDeconv(app, docalc);
                    if app.TabGroup.SelectedTab == app.SummaryTab
                        if app.SummaryDeconvGuessCheckBox.Value == 1 && isscalar(ds)
                            uialert(app.TCPAT2UIFigure, 'Deconvolution guess selected, but not calculated', 'Notice', 'Icon', 'info')
                        end
                        if app.SummaryDeconvCheckBox.Value == 1 && isscalar(dsopt)
                            uialert(app.TCPAT2UIFigure, 'Deconvolution selected, but not calculated', 'Notice', 'Icon', 'info')
                        end
                    end
                    if app.TabGroup.SelectedTab == app.RTDTab || app.SummaryDeconvGuessCheckBox.Value == 1
                        plotLine(app, ds, 'Deconvolution Guess')
                    end
                    if app.TabGroup.SelectedTab == app.RTDTab || app.SummaryDeconvCheckBox.Value == 1
                        plotLine(app, dsopt, 'Deconvolution')
                    end
                end                
            end
            
            % make the plot pretty
            app.dataaxes.Box = 'on';
            app.dataaxes.XGrid = 'on';
            app.dataaxes.YGrid = 'on';
            updateAxesTitle(app)
            if ~isempty(app.time)
                legend(app.dataaxes, app.leg)
            end
            
            % unlock the UI
            app.calculating = 0;
            if app.saving == 0
                unlockTabs(app);
                app.WorkingLabel.Text = '';
                drawnow nocallbacks
            end
            fprintf('Done updating axes.\n');
            pause(0.1)
        end
    
        % carry out the data pre-processing
        function dataPreprocessing(app)
            if isempty(app.time)
                % no pre-processing if no data has been loaded
                fprintf('Skipping pre-processing as no data is loaded...\n')
                return
            else
                fprintf('Updating pre-processing...\n');
            end
            
            % malformed input data can cause this to go wrong so work
            % in a try to catch errors
            try
                % copy working data from the read in raw data
                us = app.usrs;
                ds = app.dsrs;

                % apply calibration
                if app.PPCalibrateCheckBox.Value == 1
                    fprintf('Applying calibration...\n')
                    % linear from gradient provided in the UI
                    us = us .* app.PPUpstreamCalibration.Value;
                    ds = ds .* app.PPDownstreamCalibration.Value;
                end
                
                % apply median filter
                if app.PPMedianfilterCheckBox.Value == 1
                    us = medfilt1(us);
                    ds = medfilt1(ds);
                end

                % apply moving average
                if app.PPMovingaverageCheckBox.Value == 1
                    dt = mode(diff(app.times));
                    span = round((reverseUnitConversion(app, app.PPMovingaverageEditField.Value) / dt) / 2);
                    if span == 0
                        span = 1;
                    end
                    us = movmean(us, span);
                    ds = movmean(ds, span);
                end
                
                % subtract background
                if app.PPOffButton.Value ~= 1
                    if app.PPManualButton.Value == 1
                        % manual background subtraction
                        us = us - app.PPManualUSEditField.Value;
                        ds = ds - app.PPManualDSEditField.Value;
                    else
                        % get the size of the averaging in seconds
                        span = reverseUnitConversion(app, app.PPLinearEditField.Value);
                        dt = mode(diff(app.times));
                        span = round(span / dt);
                        if isnan(span) || span > length(app.times)
                            % we can't average over more time steps than
                            % are in the time series
                            if app.TabGroup.SelectedTab ~= app.PreProcessingTab
                                app.PPOffButton.Value = 1;
                                error('Error subtracting background')
                            else
                                error('Invalid background subtraction settings')
                            end
                        end
                        if span == 0
                            % we can't average over less than one time step
                            uialert(app.TCPAT2UIFigure, 'The background subtraction range is less than one time-step, assuming one time-step.', 'Notice', 'Icon', 'info');
                            span = 1;
                            app.PPLinearEditField.Value = unitConversion(app, span*dt);
                        end
                        % for upstream then downstream subtract the
                        % background
                        for jj=1:2
                            % copy to a temporary variable
                            if jj == 1
                                tdata = us;
                            else
                                tdata = ds;
                            end
                            k = tdata ~= 0 & ~isnan(tdata);
                            tdata = tdata(k);
                            bgtime = app.times(k);
                            
                            bg1 = mean(tdata(1:span));
                            bg2 = mean(tdata(end-span+1:end));
                            if app.PPConstantButton.Value == 1
                                % subtract constant based on start or end
                                if strcmp(app.PPConstantDropDown.Value, 'Start')
                                    tdata = tdata - bg1;
                                elseif strcmp(app.PPConstantDropDown.Value, 'End')
                                    tdata = tdata - bg2;
                                else
                                    error('An impossible drop down option was selected')
                                end
                            elseif app.PPLinearButton.Value == 1
                                % linear fit background subtraction
                                p = polyfit([bgtime(1)+(span/2)*dt bgtime(end)-(span/2)*dt], [bg1 bg2], 1);
                                tdata = tdata - polyval(p,bgtime);
                            else
                                % shouldn't get here
                                error('An impossible radio option was selected')
                            end
                            % copy back to the proper variable
                            if jj == 1
                                us(k) = tdata; 
                            else
                                ds(k) = tdata;
                            end
                        end
                    end
                end

                % turn nan concentrations to 0
                us(isnan(us)) = 0;
                ds(isnan(ds)) = 0;
                
                % trim data
                oldtimep = app.timep;
                app.traceptimes(1) = 0;
                if app.PPTrimCheckBox.Value == 1
                    k = find(strcmp(app.PPTrimDropDown.Items, app.PPTrimDropDown.Value));
                    if k == 1 % this should be manual
                        % find the indexes of the start/ends and then
                        % ensure the indexes are sensible
                        k1 = find(app.times >= reverseUnitConversion(app, app.PPFromEditField.Value), 1);
                        k2 = find(app.times >= reverseUnitConversion(app, app.PPtoEditField.Value), 1);
                        if isempty(k1) || reverseUnitConversion(app, app.PPFromEditField.Value) < app.times(1)
                            k1 = 1;
                            app.PPFromEditField.Value = unitConversion(app, app.times(1));
                        end
                        if isempty(k2)
                            k2 = length(app.times);
                            app.PPtoEditField.Value = unitConversion(app, app.times(end));
                        end
                        if k2 - k1 <= 0
                            uialert(app.TCPAT2UIFigure, 'Trim start and end times reversed or equal, resetting them', 'Notice', 'Icon', 'warning')
                            app.PPFromEditField.Value = unitConversion(app, app.times(1));
                            app.PPtoEditField.Value = unitConversion(app, app.times(end));
                            
                            dataPreprocessing(app);
                            return
                        end
                        
                        app.timep = app.times(k1:k2);
                        us = us(k1:k2);
                        ds = ds(k1:k2);
                    else
                        % these should be matched to the pre-processing trim drop down
                        trimFcns = {@onepercentpeak, @onepercentpeak2, @onepercentpeakavg, @tenoneperpeak, @ronepercentpeak, @rtenoneperpeak};
                        % run the specified trim function
                        [startU, endU, startD, endD] = trimFcns{k-1}([app.times us ds]);
                        app.timep = app.times(startU:endD);
                        us = us(startU:endD);
                        ds = ds(startU:endD);
                        app.traceptimes = app.times([startU endU startD endD]);
                        % update text fields with the calculated start and end times
                        app.PPFromEditField.Value = unitConversion(app, app.timep(1));
                        app.PPtoEditField.Value = unitConversion(app, app.timep(end));
                    end
                else
                    % no trimming
                    app.timep = app.times;
                    app.PPFromEditField.Value = unitConversion(app, app.timep(1));
                    app.PPtoEditField.Value = unitConversion(app, app.timep(end));
                end

                % profile start and end time estimates if no trimming was selected
                if app.traceptimes(1) == 0
                    % we need an estimate for this for ADZ modelling &
                    % calculating method of moments
                    [startU, endU, startD, endD] = ronepercentpeak([app.timep us ds]);
                    app.traceptimes =  app.timep([startU, endU, startD, endD]);
                end

                % set first point in time to 0?
                if app.PPTimeZero.Value == 1
                    app.traceptimes = app.traceptimes - app.timep(1);
                    app.timep = app.timep - app.timep(1);
                end
                
                % handle impact of pre-processing changes on RTD results
                if ~isempty(app.rtd)
                    % re-adjust the RTD to match the new time series length if
                    % it has already been calculated
                    oldsum = sum(app.rtd);
                    if app.DEDeconvRTDLengthCheckBox.Value == false
                        app.rtd = interp1(oldtimep-oldtimep(1), app.rtd, app.timep-app.timep(1), 'linear');
                    else
                        rtdLengthI = length(app.rtd);
                        newRtdLengthI = find(app.timep-app.timep(1) >= oldtimep(rtdLengthI)-oldtimep(1), 1);
                        app.rtd = interp1(oldtimep(1:rtdLengthI)-oldtimep(1), app.rtd, app.timep(1:newRtdLengthI)-app.timep(1), 'linear');
                    end
                    app.rtd = app.rtd/sum(app.rtd)*oldsum;
                end
                
                % handle mass-balance
                uss = sum(us);
                dss = sum(ds);
                if app.PPMassbalanceCheckBox.Value == 1
                    ds = ds ./ dss .* uss;
                    app.PPCurrentMassbalanceEditField.Value = 1;
                else
                    app.PPCurrentMassbalanceEditField.Value = dss ./ uss;
                end
                
                app.usp = us;
                app.dsp = ds;
            catch ME
                caughtError(app, ME, 'Error Pre-Processing Data')
                return
            end
        end
    
        % convert seconds to specified time unit
        function out = unitConversion(app, in, varargin)
            if isempty(varargin)
                value = app.LDTimestepDropDown.Value;
            else
                value = varargin{1};
            end
            if strcmp(value, 'seconds')
                out = in;
            elseif strcmp(value, 'minutes')
                out = in / 60;
            elseif strcmp(value, 'hours')
                out = in / 60 / 60;
            else
                error('An impossible time drop down has been selected')
            end
        end
    
        % convert current time units to seconds
        function out = reverseUnitConversion(app, in, unit)            
            if ~exist('unit', 'var') || isempty(unit)
                unit = app.LDTimestepDropDown.Value;
            end
            
            if strcmp(unit, 'seconds')
                out = in;
            elseif strcmp(unit, 'minutes')
                out = in * 60;
            elseif strcmp(unit, 'hours')
                out = in * 60 * 60;
            else
                error('An impossible time drop down has been selected (2)')
            end
        end

        % read in data - this is called after the nicedataloader has
        % specified what the data will be
        function loadData(app)
            resetData(app);
        
            fprintf('Loading data...\n')
            try
                % now put the data in time, usr, and dsr
                if app.LDSeperatedownstreamCheckBox.Value == 0
                    % start from the raw data as read in seconds
                    % read in from a single data file
                    app.usr = app.usf(:,2);
                    app.dsr = app.usf(:,3);

                    % ideally the data is already on a uniform time-step
                    alldt = unique(diff(app.usf(:,1)));
                    if length(alldt) ~= 1
                        fprintf('Non-unique time-steps detected, interpolating...\n')
                    
                        % similar to code below...
                        mit = min(app.usf(:,1));
                        mat = max(app.usf(:,1));

                        dt = mode(diff(app.usf(:,1)));
                        app.time = (mit:dt:mat)';

                        app.usr = interp1(app.usf(:,1), app.usf(:,2), app.time, 'linear', NaN); % NaN?
                        app.dsr = interp1(app.usf(:,1), app.usf(:,3), app.time, 'linear', NaN); % See below
                    else
                        app.time = app.usf(:,1);
                    end
                else
                    % start from the raw data as read in seconds
                    % read in from separate upstream and downstream matrices
                    usft = app.usf(:,1);
                    dsft = app.dsf(:,1);
                    
                    % convert the time units for the upstream data
                    must = min(usft);
                    maust = max(usft);
                    
                    % convert the time units for the downstream data
                    mdst = min(dsft);
                    madst = max(dsft);
                    
                    % work out time range for interpolation
                    mit = min([must mdst]);
                    mat = max([maust madst]);
                    
                    % usf and dsf should be in seconds, so this should all
                    % be time in seconds...
                    dt = mode(diff([app.usf(:,1); app.dsf(:,1)]));
                    app.time = (mit:dt:mat)';
                    
                    app.usr = interp1(usft, app.usf(:,2), app.time, 'linear', NaN); % was 0
                    app.dsr = interp1(dsft, app.dsf(:,2), app.time, 'linear', NaN); % but now handled in pre-processing
                    
                end
                
                % the data has been read in in seconds (nicedataloader
                % handles this conversion now)
                app.times = app.time;
                app.usrs = app.usr;
                app.dsrs = app.dsr;

            catch ME
                caughtError(app, ME, 'Error Loading Data')
                return
            end

            % plot the data (turning the plot on as it will have been
            % hidden until now)
            updateAxesPlot(app, 0)
            app.dataaxes.Visible = 'on';
        end
        
        % re-sample as a result of changing the time step field or the selected units 
        function resampleData(app)
            fprintf('Re-sampling data.\n')
            try
                value = app.LDTimestepEditField.Value;
                
                % unit conversion of field to seconds based on the field drop down
                if strcmp(app.LDTimestepDropDown.Value, 'seconds')
                    % already seconds
                elseif strcmp(app.LDTimestepDropDown.Value, 'minutes')
                    value = value * 60;
                elseif strcmp(app.LDTimestepDropDown.Value, 'hours')
                    value = value * 60 * 60;
                else
                    error('An impossible time drop down has been selected (3)')
                end
                
                % on the new time step in units
                app.times = (min(app.time):value:max(app.time))';
                app.usrs = interp1(app.time, app.usr, app.times, 'linear');
                app.dsrs = interp1(app.time, app.dsr, app.times, 'linear');
                
                % if the data is very short this was probably a mistake
                if length(app.times) < 10
                    uialert(app.TCPAT2UIFigure, 'Data is now less than 10 sample points long', 'Notice', 'Icon', 'warning')
                end
                
            catch ME
                caughtError(app, ME, 'Error Re-sampling Data')
                return
            end
        end
    
        % re-calculate the moments
        function updateMoments(app)
            % no update needed if no data loaded
            if isempty(app.time)
                fprintf('Skipping moment calculations as no data is loaded...\n')
                return
            elseif isempty(app.timep)
                fprintf('Skipping moment calculations due to no data...\n')
                return
            else
                fprintf('Updating moment calculations...\n');
                app.WorkingLabel.Text = 'Working...';
                drawnow nocallbacks
            end
            try
                % work in try catch in case of malformed data
                
                conc = [app.usp app.dsp];
                % this should be a single value, but in case a rounding
                % error slipped in use the most representative time step
                dt = mode(diff(app.timep));
                
                % we could modify this code back to use individual start and ends for each trace?
                
                % calculate moments
                % Rutherford, 1994, section 4.4.4., pages 186-189
                M0 = zeros(1,size(conc,2));
                M1 = zeros(1,size(conc,2));
                mu = zeros(1,size(conc,2));
                M2 = zeros(1,size(conc,2));
                M3 = zeros(1,size(conc,2));
                for ii=1:size(conc,2)
                    % get just the upstream and downstream profiles on
                    % their own
                    k1 = find(app.timep >= app.traceptimes((ii-1)*2+1), 1);
                    k2 = find(app.timep >= app.traceptimes((ii-1)*2+2), 1);
                    myconc = conc(k1:k2,ii);
                    mytime = app.timep(k1:k2);
                    % calculate the 0th moment, area under the curve (mass)
                    M0(ii) = sum(myconc .* dt);
                    % calculate the 1st moment
                    M1(ii) = sum(mytime .* myconc .* dt);
                    mu(ii) = M1(ii) ./ M0(ii); % centroid
                    % calculate the 2nd moment, normalised
                    M2(ii) = sum((mytime - mu(ii)).^2 .* myconc .* dt);
                    % calculate the third moment, normalised
                    M3(ii) = sum((mytime - mu(ii)).^3 .* myconc .* dt);
                end
                sigmax2 = M2 ./ M0; % variance
                skew = (M3 ./ M0) ./ (M2 ./ M0).^(3/2); % skew
                
                % store the moments
                app.moments = [M0; M1; mu; M2; sigmax2; M3; skew];
                
                % calculate tbar, U, and dispersion from increasing variance                
                tbar = diff(mu);
                U = app.LDDistanceEditField.Value / tbar;
                % normally I'd add .* U, but the code analyser complains...
                D = (diff(sigmax2) ./ diff(mu)) ./ 2 * U.^2; 
                
                % time delay for ADZ model
                timedelay = app.traceptimes(3) - app.traceptimes(1);
                
                % populate a cell array for the moments table
                % labels & units
                rowheader1 = {'M0', 'M1', 'mu', 'M2', 'Variance', 'M3', 'Skew [-]'};
                rowheader2 = {'Travel Time', '', 'ADE Velocity', 'ADE D', '', 'ADZ Time Delay'};
                if app.PPCalibrateCheckBox.Value == 0
                    units1 = {'%s', '%s2', '%s', '%s3', '%s2', '%s4', ''};
                else
                    units1 = {'ppm %s', 'ppm %s2', '%s', 'ppm %s3', '%s2', 'ppm %s4', ''};
                end
                units2 = {'%s', '', 'm/s', 'm2/s', '', '%s'};
                
                % work in the correct time units
                tunit = app.LDTimestepDropDown.Value;
                unitconversion = unitConversion(app, 1);                
                for ii=1:6
                    rowheader1{ii} = sprintf(['%s (' units1{ii} ')'], rowheader1{ii}, tunit);
                    if contains(units2{ii}, '%')
                        rowheader2{ii} = sprintf(['%s (' units2{ii} ')'], rowheader2{ii}, tunit);
                    elseif ~isempty(rowheader2{ii})
                        rowheader2{ii} = [rowheader2{ii} ' (' units2{ii} ')'];
                    end
                end
                
                % fill in the table
                data = cell(7,6);
                % headers
                data(:,1) = rowheader1;
                data(1:6,5) = rowheader2;
                % left side values
                data(1,2:3) = num2cell(M0 * unitconversion);
                data(2,2:3) = num2cell(M1 * unitconversion^2);
                data(3,2:3) = num2cell(mu * unitconversion);
                data(4,2:3) = num2cell(sigmax2 * unitconversion^3);
                data(5,2:3) = num2cell(M2 * unitconversion^2);
                data(6,2:3) = num2cell(M3 * unitconversion^4);
                data(7,2:3) = num2cell(skew);
                % right side values
                data{1,6} = tbar * unitconversion;
                data{3,6} = U;% / unitconversion;
                data{4,6} = D;% / unitconversion^2;
                data{6,6} = timedelay * unitconversion;

                % convert numbers to strings for formatting
                for ii=1:7
                    for jj=2:3
                        t = data{ii,jj};
                        if t > 1e5 || t < 0.01
                            data{ii,jj} = sprintf('%0.2e', t);
                        else
                            data{ii,jj} = sprintf('%0.4f', t);
                        end
                    end
                    % there will only be numbers for rows with model
                    % parameters in column 6
                    if ii < 7 && ~isempty(rowheader2{ii})
                        t = data{ii,6};
                        if t > 1e5 || t < 0.01
                            data{ii,6} = sprintf('%0.2e', t);
                        else
                            data{ii,6} = sprintf('%0.4f', t);
                        end
                    end
                end
                
                % set the ui element to use the data table
                app.MOMUITable.Data = data;
                
                % populate the manual fields with initial values from
                % moments calculations
                if tbar >= 0
                    if app.ADEtbarEditField.Value == 0
                        app.ADEtbarEditField.Value = unitConversion(app, tbar);
                    end
                    if app.ADZTravelTimeEditField.Value == 0
                        app.ADZTravelTimeEditField.Value = unitConversion(app, tbar);
                    end
                    if app.ADZTEditField.Value == 0 && timedelay >= 0
                        T = tbar - timedelay;
                        if T < 0
                            T = 0;
                        end
                        app.ADZTEditField.Value = unitConversion(app, T);
                    end
                end
                    
                if app.ADEUEditField.Value == 0 && U >= 0
                    app.ADEUEditField.Value = U;
                end
                
                if app.ADEDEditField.Value == 0 && D >= 0
                    app.ADEDEditField.Value = D;
                end
                
                if app.ADZTimeDelayEditField.Value == 0 && timedelay >= 0
                    app.ADZTimeDelayEditField.Value = unitConversion(app, timedelay);
                end
            
            catch ME
                caughtError(app, ME, 'Error Calculating Moments')
                return
            end
            app.WorkingLabel.Text = '';
            drawnow nocallbacks
        end
        
        % re-calculate the ADE routing prediction & optimisation
        function [ds, dsopt] = updateADErouting(app, docalc)
            % reset result
            ds = 0;
            dsopt = 0;
            % no update needed if no data loaded
            if isempty(app.time)
                fprintf('Skipping ADE as no data is loaded...\n')
                return
            elseif isempty(app.timep) || length(app.timep) <= 1
                fprintf('Skipping ADE due to data pre-processing issue...\n')
                return
            else
                fprintf('Updating ADE...\n')
            end
            try

                if app.ADEtbarEditField.Value == 0 % looks like moments haven't been calculated yet, do that
                    updateMoments(app)
                end
                
                % downstream prediction from method of moments/manual
                dt = mode(diff(app.timep));
                ds = ade(app.timep, app.usp, reverseUnitConversion(app, app.ADEtbarEditField.Value), app.ADEDEditField.Value, dt, app.ADEUEditField.Value, 0);
    
                % re-optimise the dispersion coefficient and mean velocity
                if docalc == 1
                    fprintf('Optimising ADE...\n')
                    % initial prediction based on method of moments (or
                    % last manual guess)
                    x0 = [reverseUnitConversion(app, app.ADEtbarEditField.Value) app.ADEDEditField.Value];

                    if x0(2) == 0
                        % if estimated dispersion coefficient is zero, as
                        % the field has a range [0, inf then method of
                        % moments probably predicted negative dispersion
                        % that's not physically possible, so instead since
                        % dispersion scales with velocity, so try velocity
                        % as the guess
                        x0(2) = 0.1*x0(1); 
                        
                    end
    
                    % ade routing is perfectly conservative so we should
                    % scale the data to make the optimisation more robust
                    scalefactor = 1 ./ max(app.usp);
                    fun = @(x,xdata)ade(xdata, app.usp.*scalefactor, x(1), x(2), dt, app.LDDistanceEditField.Value / x(1));
                    x0 = lsqcurvefit(fun, x0, app.timep, app.dsp.*scalefactor, [0 0], [], optimset('Display', 'none'));
                    % unscale the downstream prediction
                    dsopt = fun(x0, app.timep) ./ scalefactor;
                    
                    app.adeopt(1) = x0(1); % travel time
                    app.adeopt(2) = app.LDDistanceEditField.Value / x0(1); % velocity
                    app.adeopt(3) = x0(2); % dispersion coefficient

                    % put the optimised values back in the UI
                    app.ADEtbarEditField_2.Value = unitConversion(app, app.adeopt(1));
                    app.ADEUEditField_2.Value = app.adeopt(2);
                    app.ADEDEditField_2.Value = app.adeopt(3);

                    fprintf('done.\n')                    
                else
                    % don't re-optimise and just use the previously
                    % optimised value (usually as part of data export)
                    if app.adeopt(1) ~= 0
                        dsopt = ade(app.timep, app.usp, app.adeopt(1), app.adeopt(3), dt, app.adeopt(2), 0);
                    else
                        dsopt = 0;
                    end
                end
            catch ME
                % try to handle any optimisation errors gracefully
                caughtError(app, ME, 'Error ADE Routing')
                return
            end
        end
        
        % re-calculate the ADZ model prediction & optimisation
        function [ds,dsopt] = updateADZ(app, docalc)
            % reset result
            ds = 0;
            dsopt = 0;
            % no update needed if no data loaded
            if isempty(app.time)
                fprintf('Skipping ADZ as no data is loaded...\n')
                return
            elseif isempty(app.timep) || length(app.timep) <= 1
                fprintf('Skipping ADZ due to data pre-processing issue...\n')
                return
            else
                fprintf('Updating ADZ...\n')
            end
            try
                if app.ADZTEditField.Value == 0 % looks like moments haven't been calculated yet, do that
                    updateMoments(app)
                end
                
                % downstream prediction from method of moments/manual
                dt = mode(diff(app.timep));
                % alpha = 1 ./ T, t = traveltime - (difference in first arrival times)
                alpha = 1 ./ reverseUnitConversion(app, app.ADZTEditField.Value);                
                ds = adz(app.usp, alpha, reverseUnitConversion(app, app.ADZTimeDelayEditField.Value), dt);

                % re-optimise the alpha and travel time
                if docalc == 1
                    fprintf('Optimising ADZ... ')
                    
                    % scale the data to make the optimisation more robust
                    scalefactor = 1 ./ max(app.usp);                    
                    app.adzopt = optimizedadz(app.timep, app.usp.*scalefactor, app.dsp.*scalefactor, dt, [alpha reverseUnitConversion(app, app.ADZTimeDelayEditField.Value)]);
                    
                    % put the optimised values back in the UI
                    app.ADZTimeDelayEditField_2.Value = unitConversion(app, app.adzopt(2));
                    T = 1 / app.adzopt(1);
                    app.ADZTEditField_2.Value = unitConversion(app, T);
                    app.ADZTravelTimeEditField_2.Value = unitConversion(app, T + app.adzopt(2));
                    fprintf('done.\n')                    
                end
                
                % make a downstream prediction based on the optimised values
                if app.adzopt(1) ~= 0
                    dsopt = adz(app.usp, app.adzopt(1), app.adzopt(2), dt);
                else
                    dsopt = 0;
                end
            catch ME
                % try to handle any optimisation errors gracefully
                caughtError(app, ME, 'Error using ADZ model')
                return
            end
        end
    
        % re-deconvolve the RTD and use it for a downstream prediction
        function [ds, dsopt] = updateDeconv(app, docalc)
            % reset result
            ds = 0;
            dsopt = 0;
            % no update needed if no data loaded
            if isempty(app.time)
                fprintf('Skipping Deconvolution as no data is loaded...\n')
                return
            elseif isempty(app.timep)% || length(app.timep) <= app.DENumberofSamplePointsEditField.Value
                fprintf('Skipping Deconvolution due to data pre-processing issue...\n')
%                 if length(app.timep) <= app.DENumberofSamplePointsEditField.Value
%                     uialert(app.TCPAT2UIFigure, sprintf('Deconvolution needs at least %g data points to run', app.DENumberofSamplePointsEditField.Value), 'Deconvolution not run')
%                 end
                return
            else
                fprintf('Updating Deconvolution...\n')
                app.WorkingLabel.Text = 'Working...';
                drawnow nocallbacks
            end
            try
                % deconvolution code expects an x-by-3 matrix with time,
                % upstream, and downstream concentrations. time should
                % start at 0 so that the RTD does, so subtract start time 
                % to get time(1) = 0
                rtddata = [app.timep-app.timep(1) app.usp app.dsp];
            
                % number of sub-sample points to use when calculating the RTD
                noPoints = app.DENumberofSamplePointsEditField.Value;

                % we have two options for handling RTD length
                % option one choose RTD length manually
                % option two  make it as long as the concentration data
                if app.DEDeconvRTDLengthCheckBox.Value == true
                    rtdLength = reverseUnitConversion(app, app.DEDeconvRTDLengthEditField.Value);
                    if rtdLength == 0 || rtdLength > max(app.timep-app.timep(1))
                        % if no value is specified assume it's the length
                        % of the concentration data
                        rtdLength = max(app.timep-app.timep(1));
                        app.DEDeconvRTDLengthEditField.Value = unitConversion(app, rtdLength);
                    end
                    rtdLengthI = find((app.timep-app.timep(1)) >= rtdLength, 1, 'first');
                    [samplePoints, sampleTimes] = slopeBased3(rtddata, noPoints, rtdLengthI);
                else
                    rtdLengthI = length(app.timep); % changes how the code is called...
                    [samplePoints, sampleTimes] = slopeBased3(rtddata, noPoints);
                end
                % make sure RTD length index isn't beyond the end of the
                % sample point scheme (shouldn't get here very often)
                if rtdLengthI > samplePoints(end)
                    rtdLengthI = samplePoints(end);
                end

                % find an initial guess of the RTD to use as a starting point
                guess = ifftfft([app.timep app.usp app.dsp], (1:length(app.timep))');
                app.rtdguess = guess;
                guess = guess(1:rtdLengthI);
                ds = conv(app.usp, guess);
                ds = ds(1:length(app.dsp));
                
                % we have a little sub-window showing the RTD and CRTD,
                % reinitialise it and then plot
                cla(app.DEDeconvAxes, 'reset')
                set(app.DEDeconvAxes, 'ColorOrder', app.colors)
                yyaxis(app.DEDeconvAxes, 'left');
                h(1) = plot(app.DEDeconvAxes, unitConversion(app, app.timep(1:rtdLengthI)-app.timep(1)), guess, '--', 'Color', app.colors(1,:));
                ylabel(app.DEDeconvAxes, 'RTD [-]')
                            
                yyaxis(app.DEDeconvAxes, 'right');
                h(2) = plot(app.DEDeconvAxes, unitConversion(app, app.timep(1:rtdLengthI)-app.timep(1)), cumsum(guess), '--', 'Color', app.colors(2,:));
                ylabel(app.DEDeconvAxes, 'CRTD [-]')
                
                % legend for the deconvolution subplot sub-window
                myleg = {'Guessed RTD', 'Guessed CRTD'};
                
                % create lines for the deconvolved RTD and CRTD first so
                % that we can update to them during deconvolution
                if ~isempty(app.rtd) || docalc == 1
                    
                    yyaxis(app.DEDeconvAxes, 'left');
                    ttime = unitConversion(app, app.timep(1:rtdLengthI)-app.timep(1));
                    h(3) = line(app.DEDeconvAxes, ttime, zeros(size(ttime)), 'Color', app.colors(1,:), 'LineWidth', 1.2);
                
                    yyaxis(app.DEDeconvAxes, 'right');
                    h(4) = line(app.DEDeconvAxes, ttime, zeros(size(ttime)), 'Color', app.colors(2,:), 'LineWidth', 1.2);
                    
                    myleg = [myleg {'Deconvolved RTD', 'Deconvolved CRTD'}];
                    
                end
                
                % plot the sample points
                yyaxis(app.DEDeconvAxes, 'right');
                ylim(app.DEDeconvAxes, [0 1.1]);
                ytickformat(app.DEDeconvAxes, '%0.1f');
                hold(app.DEDeconvAxes, 'on')
                yl = ylim(app.DEDeconvAxes);
                h(end+1) = plot(app.DEDeconvAxes, unitConversion(app, sampleTimes), ones(size(sampleTimes))*(yl(2)-yl(1))*0.5+yl(1), 'go', 'LineWidth', 1.2);
                hold(app.DEDeconvAxes, 'off')
                myleg{end+1} = 'Sample Points';
                
                % sub-window formatting
                legend(app.DEDeconvAxes, h, myleg, 'Location', 'SouthWestOutside')
                app.DEDeconvAxes.Box = 'on';
                xlabel(app.DEDeconvAxes, ['Time (' app.LDTimestepDropDown.Value ')']);
                yyaxis(app.DEDeconvAxes, 'left');

                % re-deconvolve the RTD
                if docalc == 1
                    % hide the initial guess
                    h(1).Visible = false;
                    h(2).Visible = false;

                    % take the initial guess at the sample points
                    guess = guess(samplePoints)';                

                    if app.DELAMASmoothingCheckBox.Value == 1
                        % calculate the arguments for averaging
                        [~, t2] = max(guess);
                        v = diff(samplePoints)';
                        winmax = round(mean(v(t2-1:t2)));
                        interpolator = @(a,b,c)linma(a,b,c,0.1,winmax);
                    else
                        interpolator = @lin;
                    end
                    interpolator2 = @(a,b,c)interpolatorWrapper(interpolator, a, b, c, rtdLengthI);
                    
                    % run the deconvolution
                    tic
                    fprintf('RTD Going... ');
                    drawnow nocallbacks
                    [~, app.rtd] = optimize(rtddata, sampleTimes, [], [], [], interpolator2, guess, [], app.TabGroup.SelectedTab == app.RTDTab, 0, app);
                    fprintf('done. (%0.1f s elapsed)\n', toc);
                    
                    % turn the original guess back on
                    h(1).Visible = true;
                    h(2).Visible = true;
                    if app.TabGroup.SelectedTab == app.RTDTab
                        % remove the RTD prediction line added on by the deconvolution routine
                        delete(app.dataaxes.Children(1)) % this relies on a consistent Children order
                    end
                    
                end
                
                % update the sub-window to have the deconvolved RTD and CRTD
                if ~isempty(app.rtd)
                    dsopt = conv(app.usp, app.rtd);
                    dsopt = dsopt(1:length(app.dsp));
                    
                    set(h(3), 'YData', app.rtd(1:rtdLengthI));
                    set(h(4), 'YData', cumsum(app.rtd(1:rtdLengthI)));                    
                else
                    dsopt = 0;
                end
                
                % hide RTD < 0 - it's physically impossible
                yyaxis(app.DEDeconvAxes, 'left');
                yl = ylim(app.DEDeconvAxes);
                yl(1) = 0;
                ylim(app.DEDeconvAxes, yl);

            catch ME
                % try to handle any optimisation errors gracefully
                caughtError(app, ME, 'Deconvolution Error')
                return
            end
        end
                
        % set the properties for the traces & analysis to empty
        function resetData(app)
            % for resetting application state to load in fresh data
            app.times  = [];
            app.usrs = [];
            app.dsrs = [];
            
            app.timep = [];
            app.usp = [];
            app.dsp = [];
            app.uscdata = [];
            app.dscdata = [];
            
            app.moments = [];
            app.traceptimes = [0 0 0 0];
            app.adeopt = [0 0 0];
            app.adzopt = [0 0];
            app.rtdguess = [];
            app.rtd = [];
            
            app.calculating = 0;
            app.saving = 0;
            app.savedata = [];
        end
        
        % turn tabs grey while processing and lockout the ability to click on them or on the next/previous buttons
        function lockTabs(app)
            % https://stackoverflow.com/questions/37432008/enable-disable-uitabs-in-matlab/60506245
            fprintf('Locking tabs...\n');
            app.NextButton.Enable = false;
            app.PreviousButton.Enable = false;
            % app.TabGroup.Visible = 0; % we could disable tab group
            currentTab = find(app.TabGroup.SelectedTab == app.TabGroup.Children);
            if ~isempty(app.win)
                % but instead we'll use internal JS to nicely disable the tabs
                query = '';
                for ii=1:length(app.tabIDs)
                    if ~isempty(app.tabIDs{ii})
                        query = sprintf('%sdojo.style(dojo.query("[%s = ''%s'']")[0], "%s", "%s");\n', ...
                            query, 'widgetid', app.tabIDs{ii}, 'pointerEvents', 'none');
                        if ii ~= currentTab
                            query = sprintf('%sdojo.style(dojo.query("[%s = ''%s'']")[0], "%s", "%s");\n', ...
                                query, 'widgetid', app.tabIDs{ii}, 'color', 'grey');
                        end
                    end
                end
                app.win.executeJS(query);
            end
        end
        
        % turn tabs back on
        function unlockTabs(app)
            % https://stackoverflow.com/questions/37432008/enable-disable-uitabs-in-matlab/60506245
            fprintf('Unlocking tabs...\n');
            lockPrevNextButtons(app); % turn buttons back on
            % app.TabGroup.Visible = 1; % re-enable tabs the awkward way
            if ~isempty(app.win)
                % re-enable the tabs we nicely disabled with internal JS
                query = '';
                for ii=1:length(app.tabIDs)
                    if ~isempty(app.tabIDs{ii})
                        query = sprintf('%sdojo.style(dojo.query("[%s = ''%s'']")[0], "%s", "%s");\n', ...
                            query, 'widgetid', app.tabIDs{ii}, 'pointerEvents', 'auto');
                        query = sprintf('%sdojo.style(dojo.query("[%s = ''%s'']")[0], "%s", "%s");\n', ...
                            query, 'widgetid', app.tabIDs{ii}, 'color', 'black');
                    end
                end
                app.win.executeJS(query);
            end
        end
        
        % disable the previous/next change tab buttons
        function lockPrevNextButtons(app)
            % we don't want to move if an analysis is running and expecting
            % things to be in a certain state - this mostly affects the
            % deconvolution portions of the code
            % if we're at the first or last tab, disable the prev or next buttons
            currentTab = find(app.TabGroup.SelectedTab == app.TabGroup.Children);
            app.NextButton.Enable = true;
            app.PreviousButton.Enable = true;
            if currentTab == length(app.TabGroup.Children)
                app.NextButton.Enable = false;
            elseif currentTab == 1
                app.PreviousButton.Enable = false;
            end
        end
        
        % handle caught errors
        function caughtError(app, ME, mess)
            % try and both handle errors nicely so the app doesn't crash
            % and print out useful (but not excessive) debug information
            fprintf('\nWe caught an error!\n')
            app.WorkingLabel.Text = 'Error';
            if ~exist('mess', 'var') || isempty(mess)
                mess = '';
            end
            if exist('ME', 'var') && ~isempty(ME)
                % simplify exception information
                if length(ME.stack) >= 1
                    [~, f, e] = fileparts(ME.stack(1).file);
                    details = [newline 'at ' ME.stack(1).name '() in ' f e ' line ' num2str(ME.stack(1).line)];
                else
                    details = '';
                end
                mess = [mess details newline '(' getReport(ME,'basic','hyperlinks','off') ')'];
            end
            fprintf([mess newline newline])
            uialert(app.TCPAT2UIFigure, mess, 'Caught error', 'Icon', 'error', 'CloseFcn', 'uiresume(gcbf)');
            uiwait(gcbf);
            app.calculating = 0;
            app.saving = 0;
            app.WorkingLabel.Text = '';
            unlockTabs(app);
        end
    end

    methods (Access = public)

        % for the nice data loader to send back the upstream (and maybe downstream) data 
        function passUpstreamData(app, filename, timestep, usf)
            % to prevent resampledata from triggering
            resetData(app);
            app.time = [];
            app.usr = [];
            app.dsr = [];

            % no longer loading so turn browse button(s) back on
            app.LDDatafileBrowseButton.Enable = 'on';
            if app.LDSeperatedownstreamCheckBox.Value == 1
                app.LDDownstreamDatafileBrowseButton.Enable = 'on';
                app.dataaxes.Visible = 'off';
            end
            app.LDDatafileEditField.Value = filename;

            % if the units of the data being read in don't match the units
            % everything in the UI is set for, we need to make that the
            % case
            if ~strcmp(app.LDTimestepDropDown.Value, timestep)
                s = struct('PreviousValue', app.LDTimestepDropDown.Value);
                app.LDTimestepDropDown.Value = timestep;
                LDTimestepDropDownValueChanged(app, s)
            end

            % bring the data in
            app.usf = usf;
            k = isnan(app.usf(:,1)); % nan time can't exist
            app.usf = app.usf(~k,:);
%             app.usf(isnan(app.usf)) = 0; % turn nan concentration to 0

            % we're done with the loader
            app.mynicedataloader.delete

            % set time step bits
            app.LDTimestepEditField.Value = mode(diff(app.usf(:,1)));
            if reverseUnitConversion(app, 1) ~= 1
                uialert(app.TCPAT2UIFigure, ['Input time-step is in ' app.LDTimestepDropDown.Value '!'], 'Notice', 'Icon', 'info')
                app.usf(:,1) = app.usf(:,1)*reverseUnitConversion(app, 1);                
            end

            if size(app.usf,2) < 3 && app.LDSeperatedownstreamCheckBox.Value == 0
                % there's probably a separate downstream file, enable that
                fprintf('I suspect you want separate upstream and downstream files.\n')
                app.LDSeperatedownstreamCheckBox.Value = 1;
                LDSeperatedownstreamCheckBoxValueChanged(app);
                return
            end

            % fully bring in the just loaded data
            app.loadData();

        end

        % for the nice data loader to send back just upstream data
        function passDownstreamData(app, filename, timestep, dsf)

            % no longer loading so turn browse button(s) back on
            app.LDDatafileBrowseButton.Enable = 'on';
            if app.LDSeperatedownstreamCheckBox.Value == 1
                app.LDDownstreamDatafileBrowseButton.Enable = 'on';
            end
            app.LDDownstreamDatafileEditField.Value = filename;

            if ~strcmp(app.LDTimestepDropDown.Value, timestep)
                error('Downstream time unit should match upstream time unit')
            end

            % bring the data in
            app.dsf = dsf;
            k = isnan(app.dsf(:,1)); % nan time can't exist
            app.dsf = app.dsf(~k,:);
%             app.dsf(isnan(app.dsf)) = 0; % turn nan to 0.

            % we're done with the loader
            app.mynicedataloader.delete

            % set time step bits
            if reverseUnitConversion(app, 1) ~= 1
                uialert(app.TCPAT2UIFigure, ['Input time-step is in ' app.LDTimestepDropDown.Value '!'], 'Notice', 'Icon', 'info')
                app.dsf(:,1) = app.dsf(:,1)*reverseUnitConversion(app, 1);
            end

            % fully bring in the just loaded data
            app.loadData();

        end

        function niceloadCancelled(app)
            % no longer loading so turn browse button(s) back on
            app.LDDatafileBrowseButton.Enable = 'on';
            if app.LDSeperatedownstreamCheckBox.Value == 1
                app.LDDownstreamDatafileBrowseButton.Enable = 'on';
            end
            % but we didn't get any data so don't do anything else
        end

    end
        
    methods (Static, Access = public)

        % make y tick formatting always be %.[0]*f
        function ytickcallback(~, event)
            % has something to do with something I found on 
            % http://matlab184.rssing.com/chan-30918841/all_p2.html
            % but darned if I can work out what exactly now
            persistent inytickcallback
            if ~isempty(inytickcallback),  return;  end
            inytickcallback = 1; %#ok<NASGU> % disable callback re-entrancy
            ax = event.Source.Parent;

%             disp('myYtickformat?')
            if isa(ax, 'matlab.ui.control.UIAxes')
                ytickformat(ax, 'auto');
                axl = get(ax, 'YTickLabel');
                if sum(contains(axl, '.')) > 0
                    axl = axl(~contains(axl, '-'));
                    axllen = cellfun(@(x)length(x), axl);
                    if sum(axllen) == length(axl)*max(axllen)
    %                     disp('probably already formatted?')
                    else
                        % the goal is to find a label with a . in it, then we know
                        % that the format is leading zero, decimal, plus # of
                        % decimals
                        fmt = ['%0.' num2str(max(axllen) - 2) 'f'];
                        ytickformat(ax, fmt)
                    end
%                 else
%                     disp('does not need tick formatting')
                end
            end
            
            pause(0.05)
            inytickcallback = []; % re-enable callback
        end
        
        % make x tick formatting always be %.[0]*f
        function xtickcallback(~, event)
            persistent inxtickcallback
            if ~isempty(inxtickcallback),  return;  end
            inxtickcallback = 1; %#ok<NASGU> % disable callback re-entrancy
            ax = event.Source.Parent;

            if isa(ax, 'matlab.ui.control.UIAxes')
                xtickformat(ax, 'auto');
                axl = get(ax, 'XTickLabel');
                if sum(contains(axl, '.')) > 0
                    axl = axl(~contains(axl, '-'));
                    axllen = cellfun(@(x)length(x), axl);
                    if sum(axllen) == length(axl)*max(axllen)
                    else
                        fmt = ['%0.' num2str(max(axllen) - 2) 'f'];
                        xtickformat(ax, fmt)
                    end
                end
            end
            
            pause(0.05)
            inxtickcallback = []; % re-enable callback
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide while we initialise
            app.TCPAT2UIFigure.Visible = 'off';
            app.dataaxes.Visible = 'off';
            % initialisation of UI elements that can't be done in app designer directly
            axtoolbar(app.dataaxes,{'export','pan','zoomin','zoomout','restoreview'});
            addpath('functions');
            drawnow nocallbacks
            w = warning('off','MATLAB:ui:javaframe:PropertyToBeRemoved'); % R2008b compatibility
            % find the underlying html that's used to drive the UI to nicely control tabs
            warning('off','MATLAB:structOnObject')
            if strcmp(struct(struct(app.TCPAT2UIFigure).Controller).PlatformHost.getHostType, 'cefclient')
                app.win = struct(struct(struct(app.TCPAT2UIFigure).Controller).PlatformHost).CEF;
            else
                % this can't be found easily on matlab online, so we have a
                % fallback compatibility mode
                app.win = [];
            end
            warning(w);

            if ~isempty(app.win)
                % find tab details so we can properly activate/deactivate tabs
                tabHTML = jsondecode(app.win.executeJS('[...document.querySelectorAll("div.tab")].map(div => ({ innerHTML: div.querySelector("span.mwTabLabel").innerHTML, widgetid: div.getAttribute("widgetid") }));'));
                tabs = {app.TabGroup.Children.Title};
                app.tabIDs = cell(size(tabs));
    
                for ii=1:length(tabHTML)
                    for jj=1:length(tabs)
                        if strcmp(tabHTML(ii).innerHTML, tabs{jj})
                            fprintf('match %s with %s\n', tabHTML(ii).innerHTML, tabs{jj})
                            app.tabIDs{jj} = tabHTML(ii).widgetid;
                            break
                        end
                    end
                end
    
                if sum(cellfun(@(x)isempty(x), app.tabIDs)) ~= 0
                    error('Could not find my tabs')
                end
            else
                app.tabIDs = [];
            end
            
            % set font size https://github.com/StackOverflowMATLABchat/mlapptools/issues/14
            % https://www.mathworks.com/matlabcentral/answers/504775-change-font-size-of-every-components-in-uifigure-app-designer-by-following-the-uifigures-s-size
            if ~isempty(app.win)
                app.win.executeJS(['document.head.getElementsByTagName("style")[0].innerHTML = ' ...
                                   '".mwTabContainer .mwTabLabel {font-size: 13px; overflow: visible}"']);
            else
                h = findobj(app.TCPAT2UIFigure, '-property', 'FontSize');
                set(h,{'FontSize'}, num2cell(13));
            end
            
            % make sure we have nice axis tick labels with consistent
            % numbers of decimals
            hax = handle(app.dataaxes);
            addlistener(hax.YAxis, 'MarkedClean', @(x,y)app.ytickcallback(x, y));
            addlistener(hax.XAxis, 'MarkedClean', @(x,y)app.xtickcallback(x, y));
            hax = handle(app.DEDeconvAxes);
            addlistener(hax.YAxis, 'MarkedClean', @(x,y)app.ytickcallback(x, y));
            addlistener(hax.XAxis, 'MarkedClean', @(x,y)app.xtickcallback(x, y));
         
            resetData(app); % set data to zero
            app.WorkingLabel.Text = '';

            % make sure the figure title bar is visible on screen
            movegui(app.TCPAT2UIFigure, 'center')
            findfigs
            app.TCPAT2UIFigure.Visible = 'on';

            % initial welcome & help message
            message = [
                    '<b>Welcome to TCPAT2!</b><br> ' ...
                    'To begin, press Browse to load data. ' ...
                    'Work left to right, top to bottom to process data. ' ...
                    'Use the Previous and Next buttons at the bottom or click on the tabs at the top to navigate. ' ...
                ];
            uialert(app.TCPAT2UIFigure, message, 'Welcome!', 'Icon', 'info', 'Interpreter','html')

            % let's go!
            unlockTabs(app);
            drawnow nocallbacks
        end

        % Value changed function: LDSeperatedownstreamCheckBox
        function LDSeperatedownstreamCheckBoxValueChanged(app, event)
            % for having a separate downstream concentration data file
            value = app.LDSeperatedownstreamCheckBox.Value;
            app.LDDownstreamDatafileEditField.Enable = value;
            app.LDDownstreamDatafileBrowseButton.Enable = value;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % go to the next tab
            app.PreviousButton.Enable = true;
            numTabs = length(app.TabGroup.Children);
            currentTab = find(app.TabGroup.SelectedTab == app.TabGroup.Children);
            currentTab = currentTab + 1;
            if currentTab > numTabs
                currentTab = numTabs;
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(currentTab);
            TabGroupSelectionChanged(app, [])
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % go to the previous tab
            app.NextButton.Enable = true;
            currentTab = find(app.TabGroup.SelectedTab == app.TabGroup.Children);
            currentTab = currentTab - 1;
            if currentTab < 1
                currentTab = 1;
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(currentTab);
            TabGroupSelectionChanged(app, [])
        end

        % Value changed function: LDTimestepDropDown
        function LDTimestepDropDownValueChanged(app, event)
            % handle changing the time step unit
            oldunit = event.PreviousValue;
            newunit = app.LDTimestepDropDown.Value;
            xlabel(app.dataaxes, ['Time (' newunit ')']);
            
            % all the fields containing time which need to be changed to
            % match the new unit without actually changing the interval
            toupdate = {app.PPFromEditField, app.PPtoEditField, ...
                app.PPLinearEditField, app.DEDeconvRTDLengthEditField, ...
                app.PPMovingaverageEditField, ...
                app.ADEtbarEditField, app.ADEtbarEditField_2, ...
                app.ADZTravelTimeEditField, app.ADZTravelTimeEditField_2, ...
                app.ADZTimeDelayEditField, app.ADZTimeDelayEditField_2, ...
                app.ADZTEditField, app.ADZTEditField_2, ...
            };
            % read units in in their old units, and convert to new units
            for ii=1:length(toupdate)
                v = reverseUnitConversion(app, toupdate{ii}.Value, oldunit);
                toupdate{ii}.Value = unitConversion(app, v);
            end
            
            % change the labels to match the new unit
            % ADE/ADZ labels & entry units
            % moments units
            toupdate = {app.TravelTimesecondsLabel, app.TravelTimesecondsLabel_2, ...
                app.TravelTimesecondsLabel_3, app.TravelTimesecondsLabel_4, ...
                app.TimeDelaysecondsLabel, app.TimeDelaysecondsLabel_2, ...
                app.TsecondsLabel, app.TsecondsLabel_2, ...
            };
            for ii=1:length(toupdate)
                t = toupdate{ii}.Text;
                k = strfind(t, '(');
                t = t(1:k);
                toupdate{ii}.Text = [t newunit ')'];
            end
            
            % update the time step field to have the same time step but in
            % the new units
            newdt = mode(diff(unitConversion(app, app.times)));
            if ~isnan(newdt)
                app.LDTimestepEditField.Value = newdt;
            else
%                 app.LDTimestepEditField.Value = unitConversion(app, app.LDTimestepEditField.Value);
            end
            
            LDTimestepEditFieldValueChanged(app, event);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % handle changing tabs
            if app.NextButton.Enable == false && app.PreviousButton.Enable == false
                disp('Disabled returning')
                app.TabGroup.SelectedTab = event.OldValue;
                return
            end

            % lock every thing while any calculations are re-run (or
            % refreshing ui)
            lockPrevNextButtons(app);
            lockTabs(app)

            if app.TabGroup.SelectedTab == app.AboutTab
                % changing to the about tab is a special case as we remove
                % the plot in favour of references

                set(findall(app.dataaxes, 'visible', 'on'), 'visible', 'off')
                app.dataaxes.Toolbar.Visible = 'off';
                if ~isempty(app.dataaxes.Legend)
                    app.dataaxes.Legend.Visible = 'off';
                end
                app.ReferencesList.Visible = 'on';

                % note the about tab doesn't us uihtml for its text because
                % matlab does not allow uihtml to contain hyperlinks

                unlockTabs(app)
                drawnow nocallbacks
            
            else
                % we've changed to a regular tab that keeps the bottom axes
                app.ReferencesList.Visible = 'off';

                app.WorkingLabel.Text = 'Working...';
                app.calculating = 1;

                % only re-show the axes if there's something to show
                if ~isempty(app.time)
                    set(findall(app.dataaxes, 'visible', 'off'), 'visible', 'on')
                    app.dataaxes.Toolbar.Visible = 'on';
                    if ~isempty(app.dataaxes.Legend)
                        app.dataaxes.Legend.Visible = 'on';
                    end
                end
                
                % run the appropriate analyses for the current tab
                if app.TabGroup.SelectedTab == app.MomentsTab
                    updateMoments(app)
                elseif app.TabGroup.SelectedTab == app.RTDTab
                    if isempty(app.time)
                        app.DEDeconvAxes.Visible = 0;
                    else
                        app.DEDeconvAxes.Visible = 1;
                    end
                    drawnow nocallbacks
                end
                
                % refresh plot but don't recalculate downstream predictions
                updateAxesPlot(app, 0)
                % above contains unlock tabs
            end
        end

        % Button pushed function: LDDatafileBrowseButton
        function LDDatafileBrowseButtonPushed(app, event)
            % load upstream data

            % turn both browse buttons off so that only one nicedataloader
            % app can be opened at once
            app.LDDatafileBrowseButton.Enable = 'off';
            app.LDDownstreamDatafileBrowseButton.Enable = 'off';
            app.WorkingLabel.Text = 'Data import...';
            app.mynicedataloader = nicedataloader(app, app.LDDatafileEditField.Value, 1);

        end

        % Button pushed function: LDDownstreamDatafileBrowseButton
        function LDDownstreamDatafileBrowseButtonPushed(app, event)
            % load downstream data

            % turn both browse buttons off so that only one nicedataloader
            % app can be opened at once
            app.LDDatafileBrowseButton.Enable = 'off';
            app.LDDownstreamDatafileBrowseButton.Enable = 'off';
            app.WorkingLabel.Text = 'Data import...';
            app.mynicedataloader = nicedataloader(app, app.LDDownstreamDatafileEditField.Value, 2);
        end

        % Value changed function: LDTimestepEditField
        function LDTimestepEditFieldValueChanged(app, event)
            % resample when numeric time-step is changed
            if ~isempty(app.time) % assuming data loaded
                resampleData(app)
                dataPreprocessing(app)
                % refresh plot but don't recalculate downstream predictions
                updateAxesPlot(app, 0)
            else
                fprintf('Doing nothing.\n')
            end
        end

        % Value changed function: PPCalibrateCheckBox, PPUnitsDropDown
        function PPCalibrateCheckBoxValueChanged(app, event)
            % checkbox to change to calibrated data
            value = app.PPCalibrateCheckBox.Value;
            app.PPUpstreamCalibration.Enable = value;
            app.PPDownstreamCalibration.Enable = value;
            app.PPUnitsDropDown.Enable = value;
            
            if value == 1
                ylabel(app.dataaxes, ['Concentration (' app.PPUnitsDropDown.Value ')'])
            else
                ylabel(app.dataaxes, 'Relative Concentration [-]')
            end

            % refresh plot but don't recalculate downstream predictions
            dataPreprocessing(app)
            updateAxesPlot(app, 0)
        end

        % Selection changed function: PPSubtractBackgroundButtonGroup
        function PPSubtractBackgroundButtonGroupSelectionChanged(app, event)
            % change background subtraction method and alter the ui to
            % enable/disable the related options

            if app.PPConstantButton.Value == 1
                % constant background subtraction based on the mean
                % concentration at the start or end of concentrations data
                app.PPConstantDropDown.Enable = 1;
            else
                app.PPConstantDropDown.Enable = 0;
            end

            if app.PPLinearButton.Value == 1 || app.PPConstantButton.Value == 1
                % linear of constant background subtraction both have a
                % time option to take the average over
                app.PPLinearEditField.Enable = 1;
            else
                app.PPLinearEditField.Enable = 0;
            end
            
            if app.PPManualButton.Value == 1
                % manual subtraction using a single user specified value
                app.PPManualUSEditField.Enable = 1;
                app.PPManualDSEditField.Enable = 1;
                app.PPSBUpstreamLabel.Enable = 1;
                app.PPSBDownstreamLabel.Enable = 1;
            else
                app.PPManualUSEditField.Enable = 0;
                app.PPManualDSEditField.Enable = 0;
                app.PPSBUpstreamLabel.Enable = 0;
                app.PPSBDownstreamLabel.Enable = 0;
            end
            
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0)
        end

        % Value changed function: PPTrimCheckBox, PPTrimDropDown
        function TrimCheckBoxValueChanged(app, event)
            % change the data trimming method and alter the ui to
            % enable/disable the related options
            
            app.PPTrimDropDown.Enable = app.PPTrimCheckBox.Value;
            if app.PPTrimCheckBox.Value == 1 && strcmp(app.PPTrimDropDown.Value , 'Manual')
                % manual trimming
                app.PPFromEditField.Enable = 1;
                app.PPtoEditField.Enable = 1;
                app.FromEditFieldLabel.Enable = 1;
                app.toLabel.Enable = 1;
                
            else
                % no trimming or automatic trimming - neither needs the
                % manual data entry
                app.PPFromEditField.Enable = 0;
                app.PPtoEditField.Enable = 0;
                app.FromEditFieldLabel.Enable = 0;
                app.toLabel.Enable = 0;

            end
            
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0)
        end

        % Callback function: ADEDEditField, PPConstantDropDown, 
        % ...and 12 other components
        function updateAxesPlotChanged(app, event)
            % call back for a lot of elements to
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0)
        end

        % Button pushed function: ADEFromMomentsButton
        function ADEFromMomentsButtonPushed(app, event)
            % reset ade tab manual fields with values from method of
            % moments calculation
            app.WorkingLabel.Text = 'Working...';
            drawnow nocallbacks
            app.ADEtbarEditField.Value = 0;
            app.ADEUEditField.Value = 0;
            app.ADEDEditField.Value = 0;
            % refresh plot but don't recalculate downstream predictions
            updateMoments(app)
            updateAxesPlot(app, 0)
        end

        % Callback function: ADEManualoptionsButtonGroup, ADEUEditField, 
        % ...and 1 other component
        function ADEEditFieldValueChanged(app, event)
            % re-calculate the ADE prediction after altering any of the
            % manual options
            app.WorkingLabel.Text = 'Working...';
            drawnow nocallbacks
            % handle auto travel time vs auto velocity
            k = find(app.ADEManualoptionsButtonGroup.Buttons == app.ADEManualoptionsButtonGroup.SelectedObject);
            if k == 1
                app.ADEtbarEditField.Value = unitConversion(app, app.LDDistanceEditField.Value / app.ADEUEditField.Value);
                app.ADEtbarEditField.Editable = false;
                app.ADEUEditField.Editable = true;
            elseif k == 2
                app.ADEUEditField.Value = app.LDDistanceEditField.Value / reverseUnitConversion(app, app.ADEtbarEditField.Value);
                app.ADEUEditField.Editable = false;
                app.ADEtbarEditField.Editable = true;
            else
                app.ADEtbarEditField.Editable = true;
                app.ADEUEditField.Editable = true;
            end
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0)
        end

        % Button pushed function: ADEOptimiseButton, ADZOptimise, 
        % ...and 1 other component
        function updateAxesPlotCalcChanged(app, event)
            if app.calculating == 1
                fprintf('Already calculating, returning...\n')
                return
            end
            % refresh plot and recalculate downstream predictions
            updateAxesPlot(app, 1)
        end

        % Button pushed function: SummarySaveButton
        function SummarySaveButtonPushed(app, event)
            % export all of the data and options selected into a big csv file
            fprintf('Saving...\n')
            app.saving = 1;
            updateAxesPlot(app, 2)
            app.WorkingLabel.Text = 'Saving...';
            lockTabs(app);
            drawnow nocallbacks
            
            try

                % uiputfile, depending on platform, may open in an annoying
                % location - this can be fixed, but probably not worth the
                % effort
                % https://uk.mathworks.com/matlabcentral/answers/31667-open-uigetfile-on-2nd-monitor
                % https://stackoverflow.com/questions/6349410/using-uigetfile-instead-of-uigetdir-to-get-directories-in-matlab
                [filename, pathname, index] = uiputfile(app.filetypes, 'Save as...');
                
                if isequal(filename, 0) || isequal(pathname, 0)
                    disp('User pressed cancel')
                else
                    file = fullfile(pathname, filename);
                    
                    % start building up a matrix of what columns of data to
                    % save and the column headings - we'll add the selected
                    % export options to this
                    titles = {['Time (' app.LDTimestepDropDown.Value ')'], ...
                        ['Upstream - ' app.dataaxes.YAxis.Label.String], ...
                        ['Downstream - ' app.dataaxes.YAxis.Label.String]};
                    data = [titles app.leg(3:end); num2cell(app.savedata)];
                    
                    % RTD time starts at zero
                    if app.SummaryDeconvGuessCheckBox.Value == 1 || app.SummaryDeconvCheckBox.Value == 1
                        data = [data [{['RTD Time (' app.LDTimestepDropDown.Value ')']}; num2cell(app.savedata(:,1) - app.savedata(1,1))]];
                    end
                    if app.SummaryDeconvGuessCheckBox.Value == 1
                        data = [data [{'Guessed RTD [-]'} num2cell(app.rtdguess)]'];
                    end
                    if app.SummaryDeconvCheckBox.Value == 1
                        data = [data [{'RTD [-]'}; num2cell(app.rtd)]];
                    end
                    
                    filetype = app.filetypes{index,1};
                    filetype = filetype(2:find(filetype == ';', 1)-1);
                    [~,~,ext] = fileparts(file);
                    if isempty(ext) || sum(contains(app.filetypes(:,1), ext)) == 0
                        file = [file filetype]; % the user has a bad extension or no extension                    
                    end
                    
                    if exist(file, 'file')
                        % double-double check the user wants to overwrite
                        selection = uiconfirm(app.TCPAT2UIFigure, ...
                            [file ' already exists.' ...
                            newline 'Do you want to replace it?'], ...
                            'Save as...', 'Icon', 'warning', ...
                            'Options',{'Yes','No'}, 'DefaultOption', 2, 'CancelOption', 2);
                        if strcmp(selection, 'No')
                            fprintf('Won''t overwrite\n')
                            dowrite = 0;
                        else
                            dowrite = 1;
                        end
                    else
                        dowrite = 1;
                    end
                
                    if dowrite == 1
                        
                        % collect settings for for saving
                        saveinfo = cell(0,2);

                        saveinfo{end+1,1} = 'Notes';
                        if ~isempty(app.SummaryDesignationNotesEditField.Value)
                            saveinfo{end, 2} = app.SummaryDesignationNotesEditField.Value;
                        else
                            saveinfo{end, 2} = 'none';
                        end

                        saveinfo{end+1,1} = 'Save data file';
                        saveinfo{end, 2} = file;
                        saveinfo{end+1,1} = '';

                        saveinfo{end+1,1} = 'Load Tab';
                        
                        if app.LDSeperatedownstreamCheckBox.Value == 1
                            saveinfo{end+1,1} = 'Upstream data file';
                            saveinfo{end, 2} = app.LDDatafileEditField.Value;
                            
                            saveinfo{end+1,1} = 'Downstream data file';
                            saveinfo{end, 2} = app.LDDownstreamDatafileEditField.Value;
                        else
                            saveinfo{end+1,1} = 'Data file';
                            saveinfo{end, 2} = app.LDDatafileEditField.Value;
                        end
                        
                        timestep = app.LDTimestepDropDown.Value;
                        saveinfo{end+1,1} = ['Timestep (' timestep ')'];
                        saveinfo{end, 2} = app.LDTimestepEditField.Value;
                        
                        saveinfo{end+1,1} = 'Distance between measurement locations (m)';
                        saveinfo{end, 2} = app.LDDistanceEditField.Value;
                        
                        
                        saveinfo{end+1,1} = '';
                        saveinfo{end+1,1} = 'Pre-Processing Tab';
                        
                        bgsub = app.PPSubtractBackgroundButtonGroup.SelectedObject.Text;
                        saveinfo{end+1,1} = 'Subtract background';
                        saveinfo{end, 2} = bgsub;
                        
                        if strcmp(bgsub, 'Manual')
                            saveinfo{end+1,1} = 'Subtracted upstream';
                            saveinfo{end, 2} = app.PPManualUSEditField.Value;
                            saveinfo{end+1,1} = 'Subtracted downstream';
                            saveinfo{end, 2} = app.PPManualDSEditField.Value;
                        else
                            if strcmp(bgsub, 'Constant')
                                saveinfo{end+1,1} = 'From the';
                                saveinfo{end, 2} = app.PPConstantDropDown.Value;
                                saveinfo{end+1,1} = ['Averaging (' timestep ')'];
                            else %if strcmp(bgsub, 'Linear')
                                saveinfo{end+1,1} = ['Trend fit to (' timestep ') from the start and end '];
                            end
                            saveinfo{end, 2} = app.PPLinearEditField.Value;
                        end
                                                
                        if app.PPCalibrateCheckBox.Value == 1
                            saveinfo{end+1,1} = 'Linear calibration applied to';
                            saveinfo{end, 2} = app.PPUnitsDropDown.Value;
                            saveinfo{end+1,1} = 'Upstream factor';
                            saveinfo{end, 2} = app.PPUpstreamCalibration.Value;
                            saveinfo{end+1,1} = 'Downstream factor';
                            saveinfo{end, 2} = app.PPDownstreamCalibration.Value;

                        else
                            saveinfo{end+1,1} = 'Calibration';
                            saveinfo{end, 2} = 'No calibration applied';
                        end
                        
                        if app.PPMovingaverageCheckBox.Value == 1
                            saveinfo{end+1,1} = ['Moving average (' timestep ')'];
                            saveinfo{end, 2} = app.PPMovingaverageEditField.Value;
                        else
                            saveinfo{end+1,1} = 'Moving average';
                            saveinfo{end, 2} = 'No moving average applied';
                        end
                        
                        if app.PPMedianfilterCheckBox.Value == 1
                            saveinfo{end+1,1} = 'Median filter applied';
                            saveinfo{end, 2} = '(third order)';
                        else
                            saveinfo{end+1,1} = 'Median filter';
                            saveinfo{end, 2} = 'No median filter applied';
                        end
                        
                        saveinfo{end+1,1} = 'Trim method';
                        if app.PPTrimCheckBox.Value == 1
                            saveinfo{end, 2} = app.PPTrimDropDown.Value;
                        else
                            saveinfo{end, 2} = 'No trimming applied';
                        end
                        
                        saveinfo{end+1,1} = ['Trace start (' timestep ')'];
                        saveinfo{end, 2} = app.PPFromEditField.Value;
                        
                        saveinfo{end+1,1} = ['Trace end (' timestep ')'];
                        saveinfo{end, 2} = app.PPtoEditField.Value;
                        
                        saveinfo{end+1,1} = 'Mass-balance';
                        if app.PPMassbalanceCheckBox.Value == 1
                            saveinfo{end, 2} = 'Mass-balance forced';
                        else
                            saveinfo{end, 2} = 'Mass-balance unchanged';
                        end
                        
                        saveinfo{end+1,1} = 'Data mass-balance';
                        saveinfo{end, 2} = app.PPCurrentMassbalanceEditField.Value;

                        saveinfo{end+1,1} = 'Start time';
                        if app.PPTimeZero.Value == 1
                            saveinfo{end, 2} = 'Start time reset to zero';
                        else
                            saveinfo{end, 2} = 'Start time unchanged';
                        end
                        
                        saveinfo{end+1,1} = '';
                        saveinfo{end+1,1} = 'Moments Tab (Upstream)';
                        
                        saveinfo(end+1:end+7,1:2) = app.MOMUITable.Data(:,1:2);
                        
                        saveinfo{end+1,1} = '';
                        saveinfo{end+1,1} = 'Moments Tab (Downstream)';
                        
                        saveinfo(end+1:end+7,1:2) = app.MOMUITable.Data(:,[1 3]);
                        
                        saveinfo{end+1,1} = '';
                        saveinfo{end+1,1} = 'Moments Tab (Model parameters)';
                        
                        saveinfo(end+1:end+4,1:2) = app.MOMUITable.Data([1 3 4 6],5:6);
                        
                        if app.SummaryADEManualCheckBox.Value == 1
                            saveinfo{end+1,1} = '';
                            saveinfo{end+1,1} = 'ADE Tab (Manual)';
                        
                            saveinfo{end+1,1} = 'Manual Options';
                            saveinfo{end, 2} = app.ADEManualoptionsButtonGroup.SelectedObject.Text;
                        
                            saveinfo{end+1,1} = app.TravelTimesecondsLabel.Text;
                            saveinfo{end, 2} = app.ADEtbarEditField.Value;
                        
                            saveinfo{end+1,1} = 'Velocity (m/s)';
                            saveinfo{end, 2} = app.ADEUEditField.Value;
                        
                            saveinfo{end+1,1} = 'D (m2/s)';
                            saveinfo{end, 2} = app.ADEDEditField.Value;
                        end
                        
                        if app.SummaryADEOptimisedCheckBox.Value == 1       
                            saveinfo{end+1,1} = '';
                            saveinfo{end+1,1} = 'ADE Tab (Optimised)';
                        
                            saveinfo{end+1,1} = app.TravelTimesecondsLabel_2.Text;
                            saveinfo{end, 2} = app.ADEtbarEditField_2.Value;
                        
                            saveinfo{end+1,1} = 'Velocity (m/s)';
                            saveinfo{end, 2} = app.ADEUEditField_2.Value;
                        
                            saveinfo{end+1,1} = 'D (m2/s)';
                            saveinfo{end, 2} = app.ADEDEditField_2.Value;
                        end
                        
                        if app.SummaryADZManualCheckBox.Value == 1
                            saveinfo{end+1,1} = '';
                            saveinfo{end+1,1} = 'ADZ Tab (Manual)';
                        
                            saveinfo{end+1,1} = app.TravelTimesecondsLabel_3.Text;
                            saveinfo{end, 2} = app.ADZTravelTimeEditField.Value;
                        
                            saveinfo{end+1,1} = app.TimeDelaysecondsLabel.Text;
                            saveinfo{end, 2} = app.ADZTimeDelayEditField.Value;
                        
                            saveinfo{end+1,1} = app.TsecondsLabel.Text;
                            saveinfo{end, 2} = app.ADZTEditField.Value;
                        end
                        
                        if app.SummaryADZOptimisedCheckBox.Value == 1
                            saveinfo{end+1,1} = '';
                            saveinfo{end+1,1} = 'ADZ Tab (Optimised)';
                        
                            saveinfo{end+1,1} = app.TravelTimesecondsLabel_4.Text;
                            saveinfo{end, 2} = app.ADZTravelTimeEditField_2.Value;
                        
                            saveinfo{end+1,1} = app.TimeDelaysecondsLabel_2.Text;
                            saveinfo{end, 2} = app.ADZTimeDelayEditField_2.Value;
                        
                            saveinfo{end+1,1} = app.TsecondsLabel_2.Text;
                            saveinfo{end, 2} = app.ADZTEditField_2.Value;
                        end
                        
                        if app.SummaryDeconvCheckBox.Value == 1
                            saveinfo{end+1,1} = '';
                            saveinfo{end+1,1} = 'Deconvolution';
                        
                            saveinfo{end+1,1} = 'Number of sample points';
                            saveinfo{end, 2} = app.DENumberofSamplePointsEditField.Value;
                            
                            if app.DEDeconvRTDLengthCheckBox.Value == 1
                                saveinfo{end+1,1} = ['RTD Length (' timestep ')'];
                                saveinfo{end, 2} = app.DEDeconvRTDLengthEditField.Value;
                            end
                            
                            saveinfo{end+1,1} = 'Smoothing';
                            if app.DELAMASmoothingCheckBox.Value == 1
                                saveinfo{end, 2} = 'LAMA';
                            else
                                saveinfo{end, 2} = 'None';
                            end
                        end

                        % put the info on the settings into the big matrix
                        % of what we're exporting
                        data{1,size(data,2)+1} = ' ';
                        data(1:size(saveinfo,1),[1 2]+size(data,2)) = saveinfo;
                        
                        % write out the data
                        try
                            fprintf('Writing\n')
                            writecell(data, file)
                            uialert(app.TCPAT2UIFigure, ['Saved to ' file], 'Saved', 'Icon', 'success')
                        catch ME
                            caughtError(app, ME, ['Error writing file ' file])
                            return
                        end
                    end
                end
            catch ME
                caughtError(app, ME, 'Error saving')
                return
            end
            
            app.saving = 0;
            unlockTabs(app);
            app.WorkingLabel.Text = '';
            drawnow nocallbacks
        end

        % Button pushed function: ADZFromMomentsButton
        function ADZFromMomentsButtonPushed(app, event)
            % reset ade tab manual fields with values from method of
            % moments calculation
            app.WorkingLabel.Text = 'Working...';
            drawnow nocallbacks
            app.ADZTravelTimeEditField.Value = 0;
            app.ADZTEditField.Value = 0;
            app.ADZTimeDelayEditField.Value = 0;
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0);
        end

        % Value changed function: DEDeconvRTDLengthCheckBox
        function DEDeconvRTDLengthCheckBoxValueChanged(app, event)
            % enable the rtd length field depending on the rtd length checkbox
            app.DEDeconvRTDLengthEditField.Enable = app.DEDeconvRTDLengthCheckBox.Value;
            app.rtdguess = [];
            app.rtd = [];
            updateDeconv(app, 0);
        end

        % Value changed function: DEDeconvRTDLengthEditField, 
        % ...and 2 other components
        function DeconvValueChanged(app, event)
            % for changing RTD tab text fields
            app.rtdguess = [];
            app.rtd = [];
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0);
        end

        % Value changed function: PPMovingaverageCheckBox
        function PPMovingaverageCheckBoxValueChanged(app, event)
            % enable/disable moving average
            app.PPMovingaverageEditField.Enable = app.PPMovingaverageCheckBox.Value;
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0)
        end

        % Button pushed function: DEDeconvoleButton
        function DEDeconvoleButtonPushed(app, event)
            % terminate deconvolution early
            global doStop %#ok<GVMIS> 
            if isempty(doStop)
                doStop = 0;
            end
            if app.calculating == 1
                fprintf('Already deconvolving, returning...\n')
                return
            end
            % refresh plot and recalculate downstream predictions
            updateAxesPlot(app, 1);
        end

        % Value changed function: ADZTimeDelayEditField, 
        % ...and 1 other component
        function ADZEditFieldValueChanged(app, event)
            % re-calculate the ADE prediction after altering any of the
            % manual options
            app.WorkingLabel.Text = 'Working...';
            drawnow nocallbacks
            T = app.ADZTravelTimeEditField.Value - app.ADZTimeDelayEditField.Value;
            if T < 0
                uialert(app.TCPAT2UIFigure, 'T less than 0, resetting', 'Warning', 'Icon', 'warning')
                T = 0;
            end
            app.ADZTEditField.Value = T;
            % refresh plot but don't recalculate downstream predictions
            updateAxesPlot(app, 0)
        end

        % Image clicked function: AboutLogoImage
        function AboutLogoImageClicked(app, event)
            % when clicking the big logo go to the website
            web('https://github.com/fredsonnenwald/TCPAT2')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TCPAT2UIFigure = uifigure('Visible', 'off');
            app.TCPAT2UIFigure.Name = 'TCPAT2';
            app.TCPAT2UIFigure.Icon = fullfile(pathToMLAPP, 'functions', 'TCPAT2_icon.png');
            app.dataaxes = uiaxes(app.TCPAT2UIFigure);
            title(app.dataaxes, 'Data (Raw)')
            xlabel(app.dataaxes, 'Time (seconds)')
            ylabel(app.dataaxes, 'Relative Concentration [-]')
            app.TabGroup = uitabgroup(app.TCPAT2UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Tooltip = {'Load data here'};
            app.LoadTab.Title = 'Load';
            app.DatafileLabel = uilabel(app.LoadTab);
            app.DatafileLabel.Text = 'Data file';
            app.LDDatafileEditField = uieditfield(app.LoadTab, 'text');
            app.LDDatafileEditField.Editable = 'off';
            app.LDDatafileEditField.Tooltip = {'Upstream/combined data file path'};
            app.LDSeperatedownstreamCheckBox = uicheckbox(app.LoadTab);
            app.LDSeperatedownstreamCheckBox.ValueChangedFcn = createCallbackFcn(app, @LDSeperatedownstreamCheckBoxValueChanged, true);
            app.LDSeperatedownstreamCheckBox.Tooltip = {'Check to enable separate upstream and downstream concentration profiles'};
            app.LDSeperatedownstreamCheckBox.Text = 'Downstream data file';
            app.LDDownstreamDatafileEditField = uieditfield(app.LoadTab, 'text');
            app.LDDownstreamDatafileEditField.Editable = 'off';
            app.LDDownstreamDatafileEditField.Enable = 'off';
            app.LDDownstreamDatafileEditField.Tooltip = {'Downstream data file path'};
            app.LDDatafileBrowseButton = uibutton(app.LoadTab, 'push');
            app.LDDatafileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @LDDatafileBrowseButtonPushed, true);
            app.LDDatafileBrowseButton.Tooltip = {'Browse for a file'};
            app.LDDatafileBrowseButton.Text = 'Browse';
            app.LDDownstreamDatafileBrowseButton = uibutton(app.LoadTab, 'push');
            app.LDDownstreamDatafileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @LDDownstreamDatafileBrowseButtonPushed, true);
            app.LDDownstreamDatafileBrowseButton.Enable = 'off';
            app.LDDownstreamDatafileBrowseButton.Tooltip = {'Browse for a file'};
            app.LDDownstreamDatafileBrowseButton.Text = 'Browse';
            app.TimestepLabel = uilabel(app.LoadTab);
            app.TimestepLabel.Text = 'Time step';
            app.LDTimestepEditField = uieditfield(app.LoadTab, 'numeric');
            app.LDTimestepEditField.Limits = [0 Inf];
            app.LDTimestepEditField.ValueChangedFcn = createCallbackFcn(app, @LDTimestepEditFieldValueChanged, true);
            app.LDTimestepEditField.Tooltip = {'Change to change data sampling'};
            app.LDTimestepEditField.Value = 1;
            app.LDTimestepDropDown = uidropdown(app.LoadTab);
            app.LDTimestepDropDown.Items = {'seconds', 'minutes', 'hours'};
            app.LDTimestepDropDown.ValueChangedFcn = createCallbackFcn(app, @LDTimestepDropDownValueChanged, true);
            app.LDTimestepDropDown.Tooltip = {'Set the time step, this will also change axis labelling, set before reading data to set data file time step'};
            app.LDTimestepDropDown.Value = 'seconds';
            app.DistancebetweenupstreamanddownstreammLabel = uilabel(app.LoadTab);
            app.DistancebetweenupstreamanddownstreammLabel.Text = 'Distance between upstream and downstream (m)';
            app.LDDistanceEditField = uieditfield(app.LoadTab, 'numeric');
            app.LDDistanceEditField.Limits = [0 Inf];
            app.LDDistanceEditField.Tooltip = {'The distance between measurement points'};
            app.LDDistanceEditField.Value = 1;
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Tooltip = {'Data pre-processing options'};
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.PPCalibrateCheckBox = uicheckbox(app.PreProcessingTab);
            app.PPCalibrateCheckBox.ValueChangedFcn = createCallbackFcn(app, @PPCalibrateCheckBoxValueChanged, true);
            app.PPCalibrateCheckBox.Tooltip = {'Apply a linear calibration (scaling factor) to the upstream and downstream data'};
            app.PPCalibrateCheckBox.Text = 'Linear Calibration';
            app.PPTrimCheckBox = uicheckbox(app.PreProcessingTab);
            app.PPTrimCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimCheckBoxValueChanged, true);
            app.PPTrimCheckBox.Tooltip = {'Trim the trace'};
            app.PPTrimCheckBox.Text = 'Trim';
            app.PPMassbalanceCheckBox = uicheckbox(app.PreProcessingTab);
            app.PPMassbalanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPMassbalanceCheckBox.Tooltip = {'Enforce mass-balance'};
            app.PPMassbalanceCheckBox.Text = 'Mass-balance';
            app.PPSubtractBackgroundButtonGroup = uibuttongroup(app.PreProcessingTab);
            app.PPSubtractBackgroundButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PPSubtractBackgroundButtonGroupSelectionChanged, true);
            app.PPSubtractBackgroundButtonGroup.Title = 'Subtract Background';
            app.PPOffButton = uiradiobutton(app.PPSubtractBackgroundButtonGroup);
            app.PPOffButton.Tooltip = {'Do not subtract background concentration level'};
            app.PPOffButton.Text = 'Off';
            app.PPOffButton.Value = true;
            app.PPConstantButton = uiradiobutton(app.PPSubtractBackgroundButtonGroup);
            app.PPConstantButton.Tooltip = {'Subtract a constant background concentration based on the mean concentration in the start or end X time units of the trace'};
            app.PPConstantButton.Text = 'Constant';
            app.PPLinearButton = uiradiobutton(app.PPSubtractBackgroundButtonGroup);
            app.PPLinearButton.Tooltip = {'Subtract background concentration as a linear trend between the start and end mean background concentration over X time units'};
            app.PPLinearButton.Text = 'Linear';
            app.PPManualButton = uiradiobutton(app.PPSubtractBackgroundButtonGroup);
            app.PPManualButton.Tooltip = {'Subtract specified background concentrations from up and downstream'};
            app.PPManualButton.Text = 'Manual';
            app.PPConstantDropDown = uidropdown(app.PPSubtractBackgroundButtonGroup);
            app.PPConstantDropDown.Items = {'Start', 'End'};
            app.PPConstantDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPConstantDropDown.Enable = 'off';
            app.PPConstantDropDown.Tooltip = {'Average over the start or the end X time units of the trace'};
            app.PPConstantDropDown.Value = 'Start';
            app.PPManualUSEditField = uieditfield(app.PPSubtractBackgroundButtonGroup, 'numeric');
            app.PPManualUSEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPManualUSEditField.Enable = 'off';
            app.PPManualUSEditField.Tooltip = {'Subtract this value from the upstream concentration profile'};
            app.PPLinearEditField = uieditfield(app.PPSubtractBackgroundButtonGroup, 'numeric');
            app.PPLinearEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPLinearEditField.Enable = 'off';
            app.PPLinearEditField.Tooltip = {'Average over this time many units'};
            app.PPLinearEditField.Value = 5;
            app.PPManualDSEditField = uieditfield(app.PPSubtractBackgroundButtonGroup, 'numeric');
            app.PPManualDSEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPManualDSEditField.Enable = 'off';
            app.PPManualDSEditField.Tooltip = {'Subtract this value from the downstream concentration profile'};
            app.PPSBUpstreamLabel = uilabel(app.PPSubtractBackgroundButtonGroup);
            app.PPSBUpstreamLabel.Enable = 'off';
            app.PPSBUpstreamLabel.Text = 'Upstream:';
            app.PPSBDownstreamLabel = uilabel(app.PPSubtractBackgroundButtonGroup);
            app.PPSBDownstreamLabel.Enable = 'off';
            app.PPSBDownstreamLabel.Text = 'Downstream:';
            app.PPTrimDropDown = uidropdown(app.PreProcessingTab);
            app.PPTrimDropDown.Items = {'Manual', '1% of peak (simple)', '1% of peak (smart)', '1% of peak (average)', '1% of peak (10 points)', 'Reverse 1% of peak (simple)', 'Reverse 1% of peak (10 points)'};
            app.PPTrimDropDown.ValueChangedFcn = createCallbackFcn(app, @TrimCheckBoxValueChanged, true);
            app.PPTrimDropDown.Enable = 'off';
            app.PPTrimDropDown.Tooltip = {'Select an automatic trimming method or enter manual'};
            app.PPTrimDropDown.Value = 'Manual';
            app.FromEditFieldLabel = uilabel(app.PreProcessingTab);
            app.FromEditFieldLabel.Enable = 'off';
            app.FromEditFieldLabel.Text = 'From:';
            app.PPFromEditField = uieditfield(app.PreProcessingTab, 'numeric');
            app.PPFromEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPFromEditField.Enable = 'off';
            app.PPFromEditField.Tooltip = {'Specify the start of the upstream trace'};
            app.toLabel = uilabel(app.PreProcessingTab);
            app.toLabel.Enable = 'off';
            app.toLabel.Text = 'to';
            app.PPtoEditField = uieditfield(app.PreProcessingTab, 'numeric');
            app.PPtoEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPtoEditField.Enable = 'off';
            app.PPtoEditField.Tooltip = {'Specify the end of the downstream trace'};
            app.CurrentMassbalanceLabel = uilabel(app.PreProcessingTab);
            app.CurrentMassbalanceLabel.Text = 'Current Mass-balance';
            app.PPCurrentMassbalanceEditField = uieditfield(app.PreProcessingTab, 'numeric');
            app.PPCurrentMassbalanceEditField.Editable = 'off';
            app.PPCurrentMassbalanceEditField.Tooltip = {'The mass-balance of the data, mass-balance cannot be specified'};
            app.PPCurrentMassbalanceEditField.Value = 1;
            app.PPMovingaverageCheckBox = uicheckbox(app.PreProcessingTab);
            app.PPMovingaverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @PPMovingaverageCheckBoxValueChanged, true);
            app.PPMovingaverageCheckBox.Tooltip = {'Apply a moving average to smooth the data'};
            app.PPMovingaverageCheckBox.Text = 'Moving average';
            app.PPMovingaverageEditField = uieditfield(app.PreProcessingTab, 'numeric');
            app.PPMovingaverageEditField.Limits = [2.22044604925031e-16 Inf];
            app.PPMovingaverageEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPMovingaverageEditField.Enable = 'off';
            app.PPMovingaverageEditField.Tooltip = {'The moving average should cover this many units of time'};
            app.PPMovingaverageEditField.Value = 5;
            app.PPMedianfilterCheckBox = uicheckbox(app.PreProcessingTab);
            app.PPMedianfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPMedianfilterCheckBox.Tooltip = {'Apply a simple 3 point median filter to smooth the data'};
            app.PPMedianfilterCheckBox.Text = 'Median filter';
            app.PPTimeZero = uicheckbox(app.PreProcessingTab);
            app.PPTimeZero.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPTimeZero.Tooltip = {'Set the time of the first data point to zero'};
            app.PPTimeZero.Text = 'Start at t = 0';
            app.UpstreamEditFieldLabel = uilabel(app.PreProcessingTab);
            app.UpstreamEditFieldLabel.Text = 'Upstream';
            app.PPUpstreamCalibration = uieditfield(app.PreProcessingTab, 'numeric');
            app.PPUpstreamCalibration.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPUpstreamCalibration.Enable = 'off';
            app.PPUpstreamCalibration.Tooltip = {'Upstream calibration  gradient'};
            app.PPUpstreamCalibration.Value = 1;
            app.DownstreamLabel = uilabel(app.PreProcessingTab);
            app.DownstreamLabel.Text = 'Downstream';
            app.PPDownstreamCalibration = uieditfield(app.PreProcessingTab, 'numeric');
            app.PPDownstreamCalibration.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.PPDownstreamCalibration.Enable = 'off';
            app.PPDownstreamCalibration.Tooltip = {'Downstream calibration gradient'};
            app.PPDownstreamCalibration.Value = 1;
            app.PPUnitsDropDown = uidropdown(app.PreProcessingTab);
            app.PPUnitsDropDown.Items = {'ppb', 'ppm', 'ug/l', 'mg/l', '-'};
            app.PPUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PPCalibrateCheckBoxValueChanged, true);
            app.PPUnitsDropDown.Enable = 'off';
            app.PPUnitsDropDown.Tooltip = {'Calibration units'};
            app.PPUnitsDropDown.Value = 'ppb';
            app.MomentsTab = uitab(app.TabGroup);
            app.MomentsTab.Tooltip = {'Statistical moments analysis'};
            app.MomentsTab.Title = 'Moments';
            app.MOMUITable = uitable(app.MomentsTab);
            app.MOMUITable.ColumnName = {'Moment'; 'Upstream'; 'Downstream'; ''; 'Model parameter'; 'Analysis'};
            app.MOMUITable.RowName = {};
            app.MOMUITable.ColumnEditable = [false true true false false true];
            app.ADETab = uitab(app.TabGroup);
            app.ADETab.Tooltip = {'Advection-Dispersion Equation analysis'};
            app.ADETab.Title = 'ADE';
            app.TravelTimesecondsLabel = uilabel(app.ADETab);
            app.TravelTimesecondsLabel.Text = 'Travel Time (seconds)';
            app.ADEtbarEditField = uieditfield(app.ADETab, 'numeric');
            app.ADEtbarEditField.Limits = [0 Inf];
            app.ADEtbarEditField.ValueChangedFcn = createCallbackFcn(app, @ADEEditFieldValueChanged, true);
            app.ADEtbarEditField.Tooltip = {'Travel time between measurement locations'};
            app.VelocitymsLabel = uilabel(app.ADETab);
            app.VelocitymsLabel.Text = 'Velocity (m/s)';
            app.ADEUEditField = uieditfield(app.ADETab, 'numeric');
            app.ADEUEditField.ValueChangedFcn = createCallbackFcn(app, @ADEEditFieldValueChanged, true);
            app.ADEUEditField.Editable = 'off';
            app.ADEUEditField.Tooltip = {'Mean velocity between measurement locations'};
            app.Dm2sEditFieldLabel = uilabel(app.ADETab);
            app.Dm2sEditFieldLabel.Text = 'D (m2/s)';
            app.ADEDEditField = uieditfield(app.ADETab, 'numeric');
            app.ADEDEditField.Limits = [0 Inf];
            app.ADEDEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.ADEDEditField.Tooltip = {'Longitudinal dispersion coefficient'};
            app.ADEManualoptionsButtonGroup = uibuttongroup(app.ADETab);
            app.ADEManualoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ADEEditFieldValueChanged, true);
            app.ADEManualoptionsButtonGroup.Title = 'Manual options';
            app.ADEAutotbarButton = uiradiobutton(app.ADEManualoptionsButtonGroup);
            app.ADEAutotbarButton.Tooltip = {'Automatically adjust travel time depending on velocity'};
            app.ADEAutotbarButton.Text = 'Auto Travel Time';
            app.ADEAutoUButton = uiradiobutton(app.ADEManualoptionsButtonGroup);
            app.ADEAutoUButton.Tooltip = {'Automatically adjust velocity depending on travel time'};
            app.ADEAutoUButton.Text = 'Auto Velocity';
            app.ADEAutoUButton.Value = true;
            app.ADEFullmanualButton = uiradiobutton(app.ADEManualoptionsButtonGroup);
            app.ADEFullmanualButton.Tooltip = {'Specify both travel time and velocity manually'};
            app.ADEFullmanualButton.Text = 'Full manual';
            app.ADEManualAdvectionDispersionEquationRoutingLabel = uilabel(app.ADETab);
            app.ADEManualAdvectionDispersionEquationRoutingLabel.Text = 'Manual Advection-Dispersion Equation Routing';
            app.ADEFromMomentsButton = uibutton(app.ADETab, 'push');
            app.ADEFromMomentsButton.ButtonPushedFcn = createCallbackFcn(app, @ADEFromMomentsButtonPushed, true);
            app.ADEFromMomentsButton.Tooltip = {'Populate manual routing parameters with estimates from method of moments'};
            app.ADEFromMomentsButton.Text = 'From Moments';
            app.ADEOptimisedRoutingLabel = uilabel(app.ADETab);
            app.ADEOptimisedRoutingLabel.Text = 'Optimised Routing';
            app.ADEOptimiseButton = uibutton(app.ADETab, 'push');
            app.ADEOptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @updateAxesPlotCalcChanged, true);
            app.ADEOptimiseButton.Tooltip = {'Perform ADE optimisation to find best fit model parameters'};
            app.ADEOptimiseButton.Text = 'Optimise';
            app.TravelTimesecondsLabel_2 = uilabel(app.ADETab);
            app.TravelTimesecondsLabel_2.Text = 'Travel Time (seconds)';
            app.ADEtbarEditField_2 = uieditfield(app.ADETab, 'numeric');
            app.ADEtbarEditField_2.Limits = [0 Inf];
            app.ADEtbarEditField_2.Editable = 'off';
            app.ADEtbarEditField_2.Tooltip = {'Optimised travel time between measurement locations, this value cannot be specified'};
            app.VelocitymsLabel_2 = uilabel(app.ADETab);
            app.VelocitymsLabel_2.Text = 'Velocity (m/s)';
            app.ADEUEditField_2 = uieditfield(app.ADETab, 'numeric');
            app.ADEUEditField_2.Editable = 'off';
            app.ADEUEditField_2.Tooltip = {'Optimised mean velocity between measurement locations, this value cannot be specified'};
            app.Dm2sEditFieldLabel_2 = uilabel(app.ADETab);
            app.Dm2sEditFieldLabel_2.Text = 'D (m2/s)';
            app.ADEDEditField_2 = uieditfield(app.ADETab, 'numeric');
            app.ADEDEditField_2.Limits = [0 Inf];
            app.ADEDEditField_2.Editable = 'off';
            app.ADEDEditField_2.Tooltip = {'Optimised dispersion coefficient between measurement locations, this value cannot be specified'};
            app.ADZTab = uitab(app.TabGroup);
            app.ADZTab.Tooltip = {'Aggregated Dead-Zone model analysis'};
            app.ADZTab.Title = 'ADZ';
            app.TimeDelaysecondsLabel = uilabel(app.ADZTab);
            app.TimeDelaysecondsLabel.Text = 'Time Delay (seconds)';
            app.ADZTimeDelayEditField = uieditfield(app.ADZTab, 'numeric');
            app.ADZTimeDelayEditField.Limits = [0 Inf];
            app.ADZTimeDelayEditField.ValueChangedFcn = createCallbackFcn(app, @ADZEditFieldValueChanged, true);
            app.ADZTimeDelayEditField.Tooltip = {'Time delay between measurement locations'};
            app.ADZManualADZLabel = uilabel(app.ADZTab);
            app.ADZManualADZLabel.Text = 'Manual Aggregated Dead Zone';
            app.ADZOptimisedADZLabel = uilabel(app.ADZTab);
            app.ADZOptimisedADZLabel.Text = 'Optimised ADZ';
            app.TsecondsLabel = uilabel(app.ADZTab);
            app.TsecondsLabel.Text = 'T (seconds)';
            app.ADZTEditField = uieditfield(app.ADZTab, 'numeric');
            app.ADZTEditField.Limits = [0 Inf];
            app.ADZTEditField.Editable = 'off';
            app.ADZTEditField.Tooltip = {'Travel time minus time delay'};
            app.ADZFromMomentsButton = uibutton(app.ADZTab, 'push');
            app.ADZFromMomentsButton.ButtonPushedFcn = createCallbackFcn(app, @ADZFromMomentsButtonPushed, true);
            app.ADZFromMomentsButton.Tooltip = {'Populate manual parameters with estimates from the method of moments and first arrival times'};
            app.ADZFromMomentsButton.Text = 'From Moments';
            app.TimeDelaysecondsLabel_2 = uilabel(app.ADZTab);
            app.TimeDelaysecondsLabel_2.Text = 'Time Delay (seconds)';
            app.ADZTimeDelayEditField_2 = uieditfield(app.ADZTab, 'numeric');
            app.ADZTimeDelayEditField_2.Limits = [0 Inf];
            app.ADZTimeDelayEditField_2.Editable = 'off';
            app.ADZTimeDelayEditField_2.Tooltip = {'Optimised time delay between measurement locations, this value cannot be specified'};
            app.TsecondsLabel_2 = uilabel(app.ADZTab);
            app.TsecondsLabel_2.Text = 'T (seconds)';
            app.ADZTEditField_2 = uieditfield(app.ADZTab, 'numeric');
            app.ADZTEditField_2.Limits = [0 Inf];
            app.ADZTEditField_2.Editable = 'off';
            app.ADZTEditField_2.Tooltip = {'Optimised travel time minus time delay, this value cannot be specified'};
            app.ADZOptimise = uibutton(app.ADZTab, 'push');
            app.ADZOptimise.ButtonPushedFcn = createCallbackFcn(app, @updateAxesPlotCalcChanged, true);
            app.ADZOptimise.Tooltip = {'Calculate optimised ADZ solution to find best fit model parameters - this could take some time depending on the length of the trace'};
            app.ADZOptimise.Text = 'Optimise';
            app.TravelTimesecondsLabel_3 = uilabel(app.ADZTab);
            app.TravelTimesecondsLabel_3.Text = 'Travel Time (seconds)';
            app.ADZTravelTimeEditField = uieditfield(app.ADZTab, 'numeric');
            app.ADZTravelTimeEditField.Limits = [0 Inf];
            app.ADZTravelTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ADZEditFieldValueChanged, true);
            app.ADZTravelTimeEditField.Tooltip = {'Travel time between measurement locations'};
            app.TravelTimesecondsLabel_4 = uilabel(app.ADZTab);
            app.TravelTimesecondsLabel_4.Text = 'Travel Time (seconds)';
            app.ADZTravelTimeEditField_2 = uieditfield(app.ADZTab, 'numeric');
            app.ADZTravelTimeEditField_2.Editable = 'off';
            app.ADZTravelTimeEditField_2.Tooltip = {'Optimised travel time between measurement locations, this value cannot be specified'};
            app.RTDTab = uitab(app.TabGroup);
            app.RTDTab.Tooltip = {'Residence Time Distribution analysis'};
            app.RTDTab.Title = 'RTD';
            app.DEDeconvAxes = uiaxes(app.RTDTab);
            xlabel(app.DEDeconvAxes, 'X')
            ylabel(app.DEDeconvAxes, 'RTD [-]')
            app.NumberofSamplePointsLabel = uilabel(app.RTDTab);
            app.NumberofSamplePointsLabel.Text = 'Number of Sample Points';
            app.DENumberofSamplePointsEditField = uieditfield(app.RTDTab, 'numeric');
            app.DENumberofSamplePointsEditField.Limits = [5 Inf];
            app.DENumberofSamplePointsEditField.ValueChangedFcn = createCallbackFcn(app, @DeconvValueChanged, true);
            app.DENumberofSamplePointsEditField.Tooltip = {'Number of points to optimise for on the RTD'};
            app.DENumberofSamplePointsEditField.Value = 20;
            app.DELAMASmoothingCheckBox = uicheckbox(app.RTDTab);
            app.DELAMASmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeconvValueChanged, true);
            app.DELAMASmoothingCheckBox.Tooltip = {'Deconvolve a smoother RTD'};
            app.DELAMASmoothingCheckBox.Text = 'LAMA Smoothing';
            app.DELAMASmoothingCheckBox.Value = true;
            app.DEDeconvoleButton = uibutton(app.RTDTab, 'push');
            app.DEDeconvoleButton.ButtonPushedFcn = createCallbackFcn(app, @DEDeconvoleButtonPushed, true);
            app.DEDeconvoleButton.Tooltip = {'Start deconvolution - this may take some time'};
            app.DEDeconvoleButton.Text = 'Deconvolve RTD';
            app.DEDeconvRTDLengthEditField = uieditfield(app.RTDTab, 'numeric');
            app.DEDeconvRTDLengthEditField.Limits = [0 Inf];
            app.DEDeconvRTDLengthEditField.ValueChangedFcn = createCallbackFcn(app, @DeconvValueChanged, true);
            app.DEDeconvRTDLengthEditField.Enable = 'off';
            app.DEDeconvRTDLengthEditField.Tooltip = {'Trimmed length of the RTD in time units'};
            app.DEDeconvRTDLengthCheckBox = uicheckbox(app.RTDTab);
            app.DEDeconvRTDLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @DEDeconvRTDLengthCheckBoxValueChanged, true);
            app.DEDeconvRTDLengthCheckBox.Tooltip = {'Specify a specific length of the RTD, can be used to remove unusual fluctuations in the tail of the RTD'};
            app.DEDeconvRTDLengthCheckBox.Text = 'Manual RTD Length';
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Tooltip = {'Summary of analysis results and results export'};
            app.SummaryTab.Title = 'Summary';
            app.SummaryADEManualCheckBox = uicheckbox(app.SummaryTab);
            app.SummaryADEManualCheckBox.Text = 'ADE Manual';
            app.SummaryADEOptimisedCheckBox = uicheckbox(app.SummaryTab);
            app.SummaryADEOptimisedCheckBox.Text = 'ADE Optimised';
            app.SummarySaveButton = uibutton(app.SummaryTab, 'push');
            app.SummarySaveButton.ButtonPushedFcn = createCallbackFcn(app, @SummarySaveButtonPushed, true);
            app.SummarySaveButton.Tooltip = {'Save output to a CSV/XLS file'};
            app.SummarySaveButton.Text = 'Save Data';
            app.SummaryADZManualCheckBox = uicheckbox(app.SummaryTab);
            app.SummaryADZManualCheckBox.Text = 'ADZ Manual';
            app.SummaryUpdateCalcButton = uibutton(app.SummaryTab, 'push');
            app.SummaryUpdateCalcButton.ButtonPushedFcn = createCallbackFcn(app, @updateAxesPlotCalcChanged, true);
            app.SummaryUpdateCalcButton.Tooltip = {'Update all optimisations and plot'};
            app.SummaryUpdateCalcButton.Text = 'Recalculate';
            app.SummaryADZOptimisedCheckBox = uicheckbox(app.SummaryTab);
            app.SummaryADZOptimisedCheckBox.Text = 'ADZ Optimised';
            app.SummaryDeconvGuessCheckBox = uicheckbox(app.SummaryTab);
            app.SummaryDeconvGuessCheckBox.Text = 'Deconvolution guess';
            app.SummaryDeconvCheckBox = uicheckbox(app.SummaryTab);
            app.SummaryDeconvCheckBox.Text = 'Deconvolution';
            app.DesignationNotesLabel = uilabel(app.SummaryTab);
            app.DesignationNotesLabel.Text = 'Designation/Notes:';
            app.SummaryDesignationNotesEditField = uieditfield(app.SummaryTab, 'text');
            app.SummaryDesignationNotesEditField.Tooltip = {'Any additional notes to be saved with the analysis'};
            app.SummarySavePictureLabel = uilabel(app.SummaryTab);
            app.SummarySavePictureLabel.Text = 'To save a picture of the plot, move the mouse to the top right just above the axes, hover over the left-most icon, then click the disk';
            app.SummaryUpdateButton = uibutton(app.SummaryTab, 'push');
            app.SummaryUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @updateAxesPlotChanged, true);
            app.SummaryUpdateButton.Tooltip = {'Update plot to show selected lines'};
            app.SummaryUpdateButton.Text = 'Update Plot';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutLogoImage = uiimage(app.AboutTab);
            app.AboutLogoImage.ImageClickedFcn = createCallbackFcn(app, @AboutLogoImageClicked, true);
            app.AboutLogoImage.ImageSource = fullfile(pathToMLAPP, 'functions', 'TCPAT2_logo.png');
            app.AboutLabel = uilabel(app.AboutTab);
            app.AboutLabel.Text = {'TCPAT2 was created by'; 'Dr Fred Sonnenwald, based on the'; 'TCPAT concept by Dr Richard Dutton,'; 'at the University of Sheffield.'; ''; 'This work was funded by'; 'EPSRC EP/P012027/1 and'; 'University of Sheffield Unleash Your Data'; 'funding. Visit'; ''; 'for more detail.'};
            app.AboutHyperlink = uihyperlink(app.AboutTab);
            app.AboutHyperlink.URL = 'https://sheffield.ac.uk/mixing-studies';
            app.AboutHyperlink.Text = 'https://sheffield.ac.uk/mixing-studies';
            app.AboutHyperlink_2 = uihyperlink(app.AboutTab);
            app.AboutHyperlink_2.URL = 'https://github.com/fredsonnenwald/TCPAT2';
            app.AboutHyperlink_2.Text = 'Find the latest version on GitHub.';
            app.PreviousButton = uibutton(app.TCPAT2UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Tooltip = {'Previous tab'};
            app.PreviousButton.Text = '< Previous';
            app.NextButton = uibutton(app.TCPAT2UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Next tab'};
            app.NextButton.Text = 'Next >';
            app.WorkingLabel = uilabel(app.TCPAT2UIFigure);
            app.WorkingLabel.Text = 'Working...';
            app.ReferencesList = uihtml(app.TCPAT2UIFigure);
            app.ReferencesList.HTMLSource = '<!DOCTYPE html><html><head><style>body {    font-family: helvetica, sans;    font-size: 13px;}.hangingindent {  padding-left: 22px ;  text-indent: -22px ;  margin-bottom: -2px ; } </style><body><p><u>References</u></p><p class="hangingindent">Beer, T., & Young, P. C. (1983). Longitudinal dispersion in natural streams. <i>Journal of Environmental Engineering</i>, 109(5), 1049-1067.</p><p class="hangingindent">Dennis, P., Guymer, I., & Antonopoulos, C. (1999, August). <i>Optimisation of the aggregated dead zone model for solute dispersion in surcharged manholes.</i> Eighth International Conference on Urban Storm Drainage, Sydney, Australia.</p><p class="hangingindent">Fischer, H. B., List, J. E., Koh, C. R., Imberger, J., & Brooks, N. H. (1979). <i>Mixing in inland and coastal waters</i>. Academic press.</p><p class="hangingindent">Rutherford, J. C. (1994). <i>River mixing</i>. Wiley.</p><p class="hangingindent">Sonnenwald, F., Stovin, V., & Guymer, I. (2014). Configuring maximum entropy deconvolution for the identification of residence time distributions in solute transport applications. <i>Journal of Hydrologic Engineering</i>, 19(7), 1413-1421.</p><p class="hangingindent">Sonnenwald, F., Stovin, V., & Guymer, I. (2015). Deconvolving smooth residence time distributions from raw solute transport data. <i>Journal of Hydrologic Engineering</i>, 20(11), 04015022.</p><p class="hangingindent">Stovin, V. R., Guymer, I., Chappell, M. J., & Hattersley, J. G. (2010). The use of deconvolution techniques to identify the fundamental mixing characteristics of urban drainage structures. <i>Water Science and Technology</i>, 61(8), 2075-2081.</p><p class="hangingindent">Young, P., Jakeman, A., & McMurtrie, R. (1980). An instrumental variable method for model order identification. <i>Automatica</i>, 16(3), 281-294.</p></body></html>';
            app.ReferencesList.Visible = 'off';
            % Show the figure after all components are created
            app.TCPAT2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TCPAT2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TCPAT2UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TCPAT2UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TCPAT2UIFigure)
        end
    end
end