classdef nicedataloader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TCPAT2DataImportUIFigure  matlab.ui.Figure
        SheetDropDown             matlab.ui.control.DropDown
        SheetDropDownLabel        matlab.ui.control.Label
        OKButton                  matlab.ui.control.Button
        UnitsDropDown             matlab.ui.control.DropDown
        UnitsLabel                matlab.ui.control.Label
        DownstreamDropDown        matlab.ui.control.DropDown
        DownstreamLabel           matlab.ui.control.Label
        UpstreamDropDown          matlab.ui.control.DropDown
        UpstreamLabel             matlab.ui.control.Label
        TimeDropDown              matlab.ui.control.DropDown
        TimeLabel                 matlab.ui.control.Label
        BrowseButton              matlab.ui.control.Button
        FileNameField             matlab.ui.control.EditField
        FilenameLabel             matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
    end

    
    % This is a helper application for TCPAT2 to load in data files
    
    % MIT License
    % Copyright (c) 2023-2024 Fred Sonnenwald

    properties (Access = private)
        % filetypes should match filetypes in TCPAT2.mlapp
        filetypes = {'*.csv;*.CSV', 'Comma Separated Value (*.csv)'; ...
                '*.xlsx;*.XLSX;*.xls;*.XLS', 'Microsoft Excel (*.xls, *.xlsx)'; ...
                '*.txt;*.TXT;*.dat;*.DAT', 'Text Delimited File (*.txt,*.dat)'; ...
            }; % Supported file types
        CallingApp = []; % Calling application
        stream = []; % 1 - upstream, 2 - downstream
    end
    
    methods (Access = private)
        
        % handle caught errors
        function caughtError(app, ME, mess)
            % see also TCPAT2.caughtError
            fprintf('\nWe caught an error!\n')
            if ~exist('mess', 'var') || isempty(mess)
                mess = '';
            end
            if exist('ME', 'var') && ~isempty(ME)
                mess = [mess newline getReport(ME,'basic','hyperlinks','off')];
            end
            if strcmp(app.TCPAT2DataImportUIFigure.Visible, 'on')
                fprintf([mess newline newline])
                uialert(app.TCPAT2DataImportUIFigure, mess, 'Caught error', 'Icon', 'error', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf);
            else
                fprintf('%s\n%s\n', mess, getReport(ME,'basic','hyperlinks','off'));
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, filename, stream)
            % extra arguments are passed by the call from TCPAT2
            % https://www.mathworks.com/help/matlab/creating_guis/creating-multiwindow-apps-in-app-designer.html
            
            fprintf('Starting nice data loader...\n');

            % start with a file browse dialogue

            app.TCPAT2DataImportUIFigure.Visible = 'off';

            app.CallingApp = caller; % i.e., TCPAT2
            app.stream = stream; % are we looking for upstream or downstream

            if ~isempty(app.CallingApp)
                % put ourself in the middle of the TCPAT2 window
                calling_pos = get(app.CallingApp.TCPAT2UIFigure, 'Position');
                calling_center = calling_pos(1:2) + calling_pos(3:4) / 2;
                my_pos = get(app.TCPAT2DataImportUIFigure, 'Position');
                my_pos(1:2) = calling_center - my_pos(3:4) / 2;
                set(app.TCPAT2DataImportUIFigure, 'Position', my_pos)
                % findfigs
            end

            app.FileNameField.Value = filename; % the filename TCPAT2 currently knows            
            BrowseButtonPushed(app, []);

            app.TCPAT2DataImportUIFigure.Visible = 'on';

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % check and load input data files

            filename = app.FileNameField.Value;

            try
    
                % uigetfile, depending on platform, may open in an annoying
                % location - this can be fixed, but probably not worth the
                % effort
                % https://uk.mathworks.com/matlabcentral/answers/31667-open-uigetfile-on-2nd-monitor
                % https://stackoverflow.com/questions/6349410/using-uigetfile-instead-of-uigetdir-to-get-directories-in-matlab
                [myfile, mypath] = uigetfile(app.filetypes, 'Open...', filename);
                
                if isequal(myfile, 0) || isequal(mypath, 0)
                    disp('User pressed cancel')
                    % if the user still wants to cancel they will need to
                    % click the X to close
                    return
                else
                    filename = fullfile(mypath, myfile);
                end
    
                if ~exist(filename, 'file')
                    uialert(app.TCPAT2DataImportUIFigure, 'File not found!', 'File not found!')
                    return
                end
    
                app.FileNameField.Value = filename;
    
                if contains(lower(filename), '.xls') || contains(lower(filename), '.ods')
                    if strcmp(app.SheetDropDown.Visible, 'off')
                        pos = app.FileNameField.Position;
                        pos(3) = 260;
                        app.FileNameField.Position = pos;
                        app.SheetDropDown.Visible = 'on';
                        app.SheetDropDownLabel.Visible = 'on';
                    end
    
                    sn = sheetnames(filename);
                    app.SheetDropDown.Items = sn;
                    app.SheetDropDown.Enable = 'on';
    
                    data = readtable(filename, 'VariableNamingRule', 'preserve', ...
                        'Sheet', app.SheetDropDown.Value);
    
                else
                    data = readtable(filename, 'VariableNamingRule', 'preserve');
                    app.SheetDropDown.Enable = 'off';
                    app.SheetDropDown.Items = {'Sheet1'};
                end

                % add a column to the start so the user can choose to
                % ignore some rows if needed
                data = [table(ones(height(data),1,'logical'), 'VariableNames', {'Valid'}) data];
    
                app.UITable.Data = data;
    
                VNs = data.Properties.VariableNames;
                for ii=2:min(length(VNs), 26) % cheaping out on figuring out AA etc
                    if strcmp(VNs{ii}, sprintf('Var%d', ii-1))
                        VNs{ii} = ['Column ' char(64 + ii-1)];
                    end
                end
    
                app.UITable.ColumnName = VNs;
                app.UITable.RowName = 'numbered';

                VNs = VNs(2:end); % ignore the 'Valid' from everything else
    
                s = uistyle('HorizontalAlignment','left');
                addStyle(app.UITable, s, 'table', '');

                % setup valid column as editable
                k = zeros([1 width(data)], 'logical');
                k(1) = 1;
                app.UITable.ColumnEditable = k;
    
                % made valid column only as wide as checkbox
                k = repmat({'auto'}, 1, width(data));
                k{1} = 50;
                app.UITable.ColumnWidth = k;
    
                app.UITable.Enable = 'on';

                app.TimeDropDown.Items = VNs;
                TimeDropDownValueChanged(app, []);
    
                VNs2 = [VNs {'None'}];
    
                app.UpstreamDropDown.Items = VNs2;
                app.DownstreamDropDown.Items = VNs2;
    
                if app.stream == 1

                    app.UpstreamDropDown.Value = VNs2{2};
                    if length(VNs) == 2
                        app.DownstreamDropDown.Value = VNs2{end};
                    else
                        app.DownstreamDropDown.Value = VNs2{3};
                    end

                else % downstream

                    app.UpstreamDropDown.Value = VNs2{end};
                    app.DownstreamDropDown.Value = VNs2{2};

                end
    
                app.TimeDropDown.Enable = 'on';
                app.UnitsDropDown.Enable = 'on';
                app.UpstreamDropDown.Enable = 'on';
                app.DownstreamDropDown.Enable = 'on';
    
                app.OKButton.Enable = 'on';

            catch ME

                caughtError(app, ME, ['Error reading file ' filename])
                return

            end

        end

        % Value changed function: FileNameField
        function FileNameFieldValueChanged(app, event)
            BrowseButtonPushed(app, []);
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            value = app.TimeDropDown.Value;

            if contains(lower(value), 'sec')
                app.UnitsDropDown.Value = 'seconds';
            elseif contains(lower(value), 'min')
                app.UnitsDropDown.Value = 'minutes';
            elseif contains(lower(value), 'hr') || contains(lower(value), 'hour')
                app.UnitsDropDown.Value = 'hours';
            end
        end

        % Value changed function: SheetDropDown
        function SheetDropDownValueChanged(app, event)
            
            % What to do when the active sheet is changed for XLSX files
            % Modified from BrowseButtonPushed
            filename = app.FileNameField.Value;
            sheetname = app.SheetDropDown.Value;

            try

                if ~(contains(lower(filename), '.xls') || contains(lower(filename), '.ods'))
                    return
                end
    
                data = readtable(filename, 'VariableNamingRule', 'preserve', ...
                    'Sheet', sheetname);

                % add a column to the start so the user can choose to
                % ignore some rows if needed
                data = [table(ones(height(data),1,'logical'), 'VariableNames', {'Valid'}) data];
    
                app.UITable.Data = data;
        
                VNs = data.Properties.VariableNames;
                for ii=2:min(length(VNs), 26) % cheaping out on figuring out AA etc
                    if strcmp(VNs{ii}, sprintf('Var%d', ii-1))
                        VNs{ii} = ['Column ' char(64 + ii-1)];
                    end
                end

                app.UITable.ColumnName = VNs;
                app.UITable.RowName = 'numbered';
                
                VNs = VNs(2:end); % ignore the 'Valid' from everything else
    
                oldTimeValue = app.TimeDropDown.Value;
                oldUsValue = app.UpstreamDropDown.Value;
                oldDsValue = app.DownstreamDropDown.Value;
    
                app.TimeDropDown.Items = VNs;
                TimeDropDownValueChanged(app, []);
                VNs2 = [VNs {'None'}];
                app.UpstreamDropDown.Items = VNs2;
                app.DownstreamDropDown.Items = VNs2;
    
                k = find(contains(VNs, oldTimeValue));
                if ~isempty(k) && length(k) == 1
                    app.TimeDropDown.Value = VNs(k);
                end
    
                k = find(contains(VNs2, oldUsValue));
                if ~isempty(k) && length(k) == 1
                    app.UpstreamDropDown.Value = VNs2(k);
                else
                    if app.stream == 1
                        app.UpstreamDropDown.Value = VNs2{2};
                    else
                        app.UpstreamDropDown.Value = VNs2{end};
                    end
                end
    
                k = find(contains(VNs2, oldDsValue));
                if ~isempty(k) && length(k) == 1

                    app.DownstreamDropDown.Value = VNs2(k);

                else

                    if app.stream == 1
                        if length(VNs) == 2
                            app.DownstreamDropDown.Value = VNs2{end};
                        else
                            app.DownstreamDropDown.Value = VNs2{3};
                        end
                    else
                        app.DownstreamDropDown.Value = VNs2{2};
                    end

                end


            catch ME

                caughtError(app, ME, ['Error reading file ' filename ', sheet ' sheetname])
                return
                
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            % pass the data back to TCPAT2 if all the choices have been
            % made (column assignments etc)
            
            if app.stream == 1 && strcmp(app.UpstreamDropDown.Value, 'None')
                uialert(app.TCPAT2DataImportUIFigure, ...
                    'A upstream column must be selected.', 'Check selection!')
                return
            elseif app.stream == 2 && strcmp(app.DownstreamDropDown.Value, 'None')
                uialert(app.TCPAT2DataImportUIFigure, ...
                    'A downstream column must be selected', 'Check selection!')
                return
            end

            filename = app.FileNameField.Value;

            % put together the data into a matrix

            % send to TCPAT2 the 2 or 3 columns of data
            % send the units choice
            % send the selected filename

            valid = app.UITable.Data.Valid;
            k = find(strcmp(app.UITable.ColumnName, app.TimeDropDown.Value));
            time = table2array(app.UITable.Data(valid,k));

            try

                if app.stream == 1
                    
                    usf(:,1) = time;

                    k = find(strcmp(app.UITable.ColumnName, app.UpstreamDropDown.Value));
                    usf(:,2) = table2array(app.UITable.Data(valid,k));
    
                    if ~strcmp(app.DownstreamDropDown.Value, 'None')
                        k = find(strcmp(app.UITable.ColumnName, app.DownstreamDropDown.Value));
                        usf(:,3) = table2array(app.UITable.Data(valid,k));
                    end

                    % ensure time is in ascending order - this may not be a great idea?
%                     [~, k] = sort(time);
%                     usf = usf(k,:);

                    if ~isempty(app.CallingApp)
                        app.CallingApp.passUpstreamData(filename, app.UnitsDropDown.Value, usf)
                    end
    
                elseif app.stream == 2
    
                    dsf(:,1) = time;
                    k = find(strcmp(app.UITable.ColumnName, app.DownstreamDropDown.Value));
                    dsf(:,2) = table2array(app.UITable.Data(valid,k));

                    % ensure time is in ascending order - this may not be a great idea?
%                     [~, k] = sort(time);
%                     dsf = dsf(k,:);

                    if ~isempty(app.CallingApp)
                        app.CallingApp.passDownstreamData(filename, app.UnitsDropDown.Value, dsf)
                    end
    
                end

            catch ME

                caughtError(app, ME, 'Error reading data')
                return               

            end

        end

        % Close request function: TCPAT2DataImportUIFigure
        function TCPAT2DataImportUIFigureCloseRequest(app, event)
            if ~isempty(app.CallingApp) && isvalid(app.CallingApp)
                app.CallingApp.niceloadCancelled
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TCPAT2DataImportUIFigure = uifigure('Visible', 'off');
            app.TCPAT2DataImportUIFigure.Name = 'TCPAT2 Data Import';
            app.TCPAT2DataImportUIFigure.Icon = fullfile(pathToMLAPP, 'functions', 'TCPAT2_icon.png');
            app.TCPAT2DataImportUIFigure.CloseRequestFcn = createCallbackFcn(app, @TCPAT2DataImportUIFigureCloseRequest, true);
            app.UITable = uitable(app.TCPAT2DataImportUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.UITable.Tooltip = {'Data preview'};
            app.UITable.Enable = 'off';
            app.FilenameLabel = uilabel(app.TCPAT2DataImportUIFigure);
            app.FilenameLabel.Text = 'File name:';
            app.FileNameField = uieditfield(app.TCPAT2DataImportUIFigure, 'text');
            app.FileNameField.ValueChangedFcn = createCallbackFcn(app, @FileNameFieldValueChanged, true);
            app.FileNameField.Tooltip = {'Data file path'};
            app.FileNameField.Value = 'sampledata.csv';
            app.BrowseButton = uibutton(app.TCPAT2DataImportUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Browse for a file'};
            app.BrowseButton.Text = 'Browse';
            app.TimeLabel = uilabel(app.TCPAT2DataImportUIFigure);
            app.TimeLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.TCPAT2DataImportUIFigure);
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Enable = 'off';
            app.TimeDropDown.Tooltip = {'Select the time column'};
            app.UpstreamLabel = uilabel(app.TCPAT2DataImportUIFigure);
            app.UpstreamLabel.Text = 'Upstream';
            app.UpstreamDropDown = uidropdown(app.TCPAT2DataImportUIFigure);
            app.UpstreamDropDown.Enable = 'off';
            app.UpstreamDropDown.Tooltip = {'Select the upstream concentrations column'};
            app.DownstreamLabel = uilabel(app.TCPAT2DataImportUIFigure);
            app.DownstreamLabel.Text = 'Downstream';
            app.DownstreamDropDown = uidropdown(app.TCPAT2DataImportUIFigure);
            app.DownstreamDropDown.Enable = 'off';
            app.DownstreamDropDown.Tooltip = {'Select the downstream concentrations column'};
            app.UnitsLabel = uilabel(app.TCPAT2DataImportUIFigure);
            app.UnitsLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.TCPAT2DataImportUIFigure);
            app.UnitsDropDown.Items = {'seconds', 'minutes', 'hours'};
            app.UnitsDropDown.Enable = 'off';
            app.UnitsDropDown.Tooltip = {'Select the time units'};
            app.UnitsDropDown.Value = 'seconds';
            app.OKButton = uibutton(app.TCPAT2DataImportUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Tooltip = {'Use selected data'};
            app.OKButton.Text = 'OK';
            app.SheetDropDownLabel = uilabel(app.TCPAT2DataImportUIFigure);
            app.SheetDropDownLabel.Visible = 'off';
            app.SheetDropDownLabel.Text = 'Sheet';
            app.SheetDropDown = uidropdown(app.TCPAT2DataImportUIFigure);
            app.SheetDropDown.ValueChangedFcn = createCallbackFcn(app, @SheetDropDownValueChanged, true);
            app.SheetDropDown.Visible = 'off';
            app.SheetDropDown.Tooltip = {'Select a spreadsheet sheet'};
            % Show the figure after all components are created
            app.TCPAT2DataImportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nicedataloader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TCPAT2DataImportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TCPAT2DataImportUIFigure)
        end
    end
end